classdef DiagnosisSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        DiseaseTextArea                 matlab.ui.control.TextArea
        DiseaseTextAreaLabel            matlab.ui.control.Label
        PredictButton                   matlab.ui.control.Button
        SymptomsListBox                 matlab.ui.control.ListBox
        SymptomsListBoxLabel            matlab.ui.control.Label
        HearthDiseaseRiskTab            matlab.ui.container.Tab
        HistoyofstrokeCheckBox          matlab.ui.control.CheckBox
        HistoryofdiabetesDropDown       matlab.ui.control.DropDown
        HistoryofdiabetesDropDownLabel  matlab.ui.control.Label
        AgeDropDown                     matlab.ui.control.DropDown
        AgeDropDownLabel                matlab.ui.control.Label
        GeneralhealthDropDown           matlab.ui.control.DropDown
        GeneralhealthDropDownLabel      matlab.ui.control.Label
        DaysofgoodphysicalhealthpermonthSpinner  matlab.ui.control.Spinner
        DaysofgoodphysicalhealthpermonthSpinnerLabel  matlab.ui.control.Label
        DaysofgoodmentalhealthpermonthSpinner  matlab.ui.control.Spinner
        DaysofgoodmentalhealthpermonthSpinnerLabel  matlab.ui.control.Label
        DifficultywalkingorclimbingstairsCheckBox  matlab.ui.control.CheckBox
        PhysicalactivitiyduringpastmonthCheckBox  matlab.ui.control.CheckBox
        Heavydrinking14weeklyformale7weeklyforfemaleCheckBox  matlab.ui.control.CheckBox
        Smokedmorethan100cigarettesCheckBox  matlab.ui.control.CheckBox
        HistoyofhighbloodpressureCheckBox  matlab.ui.control.CheckBox
        HistoyofhighbloodcholesterolCheckBox  matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        BMIEditField_2                  matlab.ui.control.NumericEditField
        BMIEditField_2Label             matlab.ui.control.Label
        SexDropDown_2                   matlab.ui.control.DropDown
        SexDropDown_2Label              matlab.ui.control.Label
        HeartDiseaseDiagnosisTab        matlab.ui.container.Tab
        RestingelectrocardiographicresultsSpinner  matlab.ui.control.Spinner
        RestingelectrocardiographicresultsSpinnerLabel  matlab.ui.control.Label
        ofmajorvesselscoloredbyfluoroscopySpinner  matlab.ui.control.Spinner
        ofmajorvesselscoloredbyfluoroscopySpinnerLabel  matlab.ui.control.Label
        ThalassemiaDropDown             matlab.ui.control.DropDown
        ThalassemiaDropDownLabel        matlab.ui.control.Label
        Fastingbloodsugar120mgdlDropDown  matlab.ui.control.DropDown
        Fastingbloodsugar120mgdlDropDownLabel  matlab.ui.control.Label
        SerumCholesterolmgdlEditField   matlab.ui.control.NumericEditField
        SerumCholesterolmgdlEditFieldLabel  matlab.ui.control.Label
        ChestPainSpinner                matlab.ui.control.Spinner
        ChestPainSpinnerLabel           matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        RestingbloodpressureEditField   matlab.ui.control.NumericEditField
        RestingbloodpressureEditFieldLabel  matlab.ui.control.Label
        AgeEditField_2                  matlab.ui.control.NumericEditField
        AgeEditField_2Label             matlab.ui.control.Label
        DiabetesTab                     matlab.ui.container.Tab
        InsulinEditField                matlab.ui.control.NumericEditField
        InsulinEditFieldLabel           matlab.ui.control.Label
        GlucoseEditField                matlab.ui.control.NumericEditField
        GlucoseEditFieldLabel           matlab.ui.control.Label
        BloodPressureEditField          matlab.ui.control.NumericEditField
        BloodPressureEditFieldLabel     matlab.ui.control.Label
        SkinThicknessEditField          matlab.ui.control.NumericEditField
        SkinThicknessEditFieldLabel     matlab.ui.control.Label
        BMIEditField                    matlab.ui.control.NumericEditField
        BMIEditFieldLabel               matlab.ui.control.Label
        ofPregnaciesEditField           matlab.ui.control.NumericEditField
        ofPregnaciesEditFieldLabel      matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        mySymptoms
        myModel
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mySymptoms = readtable("Datasets\General\Symptom-severity.csv"); % mySymptoms is a table with variables "Symptom" and "weight"
            app.SymptomsListBox.Items = app.mySymptoms.Symptom; % display array extracted from "Symptom" variable
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            obj = DummyObjectMara("test"); 
            app.DiseaseTextArea.Value = obj.Property1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.SymptomsListBoxLabel = uilabel(app.GeneralTab);
            app.SymptomsListBoxLabel.Text = 'Symptoms';
            app.SymptomsListBox = uilistbox(app.GeneralTab);
            app.SymptomsListBox.Items = {'A, B, C'};
            app.SymptomsListBox.Multiselect = 'on';
            app.SymptomsListBox.Value = {};
            app.PredictButton = uibutton(app.GeneralTab, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.DiseaseTextAreaLabel = uilabel(app.GeneralTab);
            app.DiseaseTextAreaLabel.Text = 'Disease';
            app.DiseaseTextArea = uitextarea(app.GeneralTab);
            app.HearthDiseaseRiskTab = uitab(app.TabGroup);
            app.HearthDiseaseRiskTab.Title = 'Hearth Disease Risk';
            app.SexDropDown_2Label = uilabel(app.HearthDiseaseRiskTab);
            app.SexDropDown_2Label.Text = 'Sex';
            app.SexDropDown_2 = uidropdown(app.HearthDiseaseRiskTab);
            app.SexDropDown_2.Items = {'Male', 'Female'};
            app.SexDropDown_2.Value = 'Male';
            app.BMIEditField_2Label = uilabel(app.HearthDiseaseRiskTab);
            app.BMIEditField_2Label.Text = 'BMI';
            app.BMIEditField_2 = uieditfield(app.HearthDiseaseRiskTab, 'numeric');
            app.BMIEditField_2.Limits = [1 999];
            app.BMIEditField_2.Value = 1;
            app.CheckBox = uicheckbox(app.HearthDiseaseRiskTab);
            app.CheckBox.Text = 'History of angina or coronary heart disease.';
            app.HistoyofhighbloodcholesterolCheckBox = uicheckbox(app.HearthDiseaseRiskTab);
            app.HistoyofhighbloodcholesterolCheckBox.Text = 'Histoy of high blood cholesterol.';
            app.HistoyofhighbloodpressureCheckBox = uicheckbox(app.HearthDiseaseRiskTab);
            app.HistoyofhighbloodpressureCheckBox.Text = 'Histoy of high blood pressure.';
            app.Smokedmorethan100cigarettesCheckBox = uicheckbox(app.HearthDiseaseRiskTab);
            app.Smokedmorethan100cigarettesCheckBox.Text = 'Smoked more than 100 cigarettes.';
            app.Heavydrinking14weeklyformale7weeklyforfemaleCheckBox = uicheckbox(app.HearthDiseaseRiskTab);
            app.Heavydrinking14weeklyformale7weeklyforfemaleCheckBox.Text = 'Heavy drinking (14 weekly for male, 7 weekly for female)';
            app.PhysicalactivitiyduringpastmonthCheckBox = uicheckbox(app.HearthDiseaseRiskTab);
            app.PhysicalactivitiyduringpastmonthCheckBox.Text = 'Physical activitiy during past month.';
            app.DifficultywalkingorclimbingstairsCheckBox = uicheckbox(app.HearthDiseaseRiskTab);
            app.DifficultywalkingorclimbingstairsCheckBox.Text = 'Difficulty walking or climbing stairs.';
            app.DaysofgoodmentalhealthpermonthSpinnerLabel = uilabel(app.HearthDiseaseRiskTab);
            app.DaysofgoodmentalhealthpermonthSpinnerLabel.Text = 'Days of good mental health per month.';
            app.DaysofgoodmentalhealthpermonthSpinner = uispinner(app.HearthDiseaseRiskTab);
            app.DaysofgoodmentalhealthpermonthSpinner.Limits = [0 30];
            app.DaysofgoodphysicalhealthpermonthSpinnerLabel = uilabel(app.HearthDiseaseRiskTab);
            app.DaysofgoodphysicalhealthpermonthSpinnerLabel.Text = 'Days of good physical health per month.';
            app.DaysofgoodphysicalhealthpermonthSpinner = uispinner(app.HearthDiseaseRiskTab);
            app.DaysofgoodphysicalhealthpermonthSpinner.Limits = [0 30];
            app.GeneralhealthDropDownLabel = uilabel(app.HearthDiseaseRiskTab);
            app.GeneralhealthDropDownLabel.Text = 'General health';
            app.GeneralhealthDropDown = uidropdown(app.HearthDiseaseRiskTab);
            app.GeneralhealthDropDown.Items = {'Excellent', 'Very good', 'Good', 'Fair', 'Poor'};
            app.GeneralhealthDropDown.Value = 'Excellent';
            app.AgeDropDownLabel = uilabel(app.HearthDiseaseRiskTab);
            app.AgeDropDownLabel.Text = 'Age';
            app.AgeDropDown = uidropdown(app.HearthDiseaseRiskTab);
            app.AgeDropDown.Items = {'18-24', '25-29', '30-34', '35-39', '40-44', '45-49', '50-54', '55-59', '60-64', '65-69', '70-74', '74-79', '80-older'};
            app.AgeDropDown.Value = '18-24';
            app.HistoryofdiabetesDropDownLabel = uilabel(app.HearthDiseaseRiskTab);
            app.HistoryofdiabetesDropDownLabel.Text = 'History of diabetes';
            app.HistoryofdiabetesDropDown = uidropdown(app.HearthDiseaseRiskTab);
            app.HistoryofdiabetesDropDown.Items = {'No', 'Yes, during pregnancy', 'Yes'};
            app.HistoryofdiabetesDropDown.Value = 'No';
            app.HistoyofstrokeCheckBox = uicheckbox(app.HearthDiseaseRiskTab);
            app.HistoyofstrokeCheckBox.Text = 'Histoy of stroke.';
            app.HeartDiseaseDiagnosisTab = uitab(app.TabGroup);
            app.HeartDiseaseDiagnosisTab.Title = 'Heart Disease Diagnosis';
            app.AgeEditField_2Label = uilabel(app.HeartDiseaseDiagnosisTab);
            app.AgeEditField_2Label.Text = 'Age';
            app.AgeEditField_2 = uieditfield(app.HeartDiseaseDiagnosisTab, 'numeric');
            app.RestingbloodpressureEditFieldLabel = uilabel(app.HeartDiseaseDiagnosisTab);
            app.RestingbloodpressureEditFieldLabel.Text = 'Resting blood pressure';
            app.RestingbloodpressureEditField = uieditfield(app.HeartDiseaseDiagnosisTab, 'numeric');
            app.SexDropDownLabel = uilabel(app.HeartDiseaseDiagnosisTab);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.HeartDiseaseDiagnosisTab);
            app.SexDropDown.Items = {'Male', 'Female'};
            app.SexDropDown.Value = 'Male';
            app.ChestPainSpinnerLabel = uilabel(app.HeartDiseaseDiagnosisTab);
            app.ChestPainSpinnerLabel.Text = 'Chest Pain';
            app.ChestPainSpinner = uispinner(app.HeartDiseaseDiagnosisTab);
            app.ChestPainSpinner.Limits = [0 4];
            app.SerumCholesterolmgdlEditFieldLabel = uilabel(app.HeartDiseaseDiagnosisTab);
            app.SerumCholesterolmgdlEditFieldLabel.Text = 'Serum Cholesterol (mg/dl)';
            app.SerumCholesterolmgdlEditField = uieditfield(app.HeartDiseaseDiagnosisTab, 'numeric');
            app.Fastingbloodsugar120mgdlDropDownLabel = uilabel(app.HeartDiseaseDiagnosisTab);
            app.Fastingbloodsugar120mgdlDropDownLabel.Text = 'Fasting blood sugar > 120 mg/dl';
            app.Fastingbloodsugar120mgdlDropDown = uidropdown(app.HeartDiseaseDiagnosisTab);
            app.Fastingbloodsugar120mgdlDropDown.Items = {'No', 'Yes'};
            app.Fastingbloodsugar120mgdlDropDown.Value = 'No';
            app.ThalassemiaDropDownLabel = uilabel(app.HeartDiseaseDiagnosisTab);
            app.ThalassemiaDropDownLabel.Text = 'Thalassemia';
            app.ThalassemiaDropDown = uidropdown(app.HeartDiseaseDiagnosisTab);
            app.ThalassemiaDropDown.Items = {'Normal', 'FIxed defect', 'Reversible defect'};
            app.ThalassemiaDropDown.Value = 'Normal';
            app.ofmajorvesselscoloredbyfluoroscopySpinnerLabel = uilabel(app.HeartDiseaseDiagnosisTab);
            app.ofmajorvesselscoloredbyfluoroscopySpinnerLabel.Text = '# of major vessels colored by fluoroscopy ';
            app.ofmajorvesselscoloredbyfluoroscopySpinner = uispinner(app.HeartDiseaseDiagnosisTab);
            app.ofmajorvesselscoloredbyfluoroscopySpinner.Limits = [0 4];
            app.RestingelectrocardiographicresultsSpinnerLabel = uilabel(app.HeartDiseaseDiagnosisTab);
            app.RestingelectrocardiographicresultsSpinnerLabel.Text = 'Resting electrocardiographic results';
            app.RestingelectrocardiographicresultsSpinner = uispinner(app.HeartDiseaseDiagnosisTab);
            app.RestingelectrocardiographicresultsSpinner.Limits = [0 2];
            app.DiabetesTab = uitab(app.TabGroup);
            app.DiabetesTab.Title = 'Diabetes';
            app.AgeEditFieldLabel = uilabel(app.DiabetesTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.ofPregnaciesEditFieldLabel = uilabel(app.DiabetesTab);
            app.ofPregnaciesEditFieldLabel.Text = '# of Pregnacies';
            app.ofPregnaciesEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.BMIEditFieldLabel = uilabel(app.DiabetesTab);
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.SkinThicknessEditFieldLabel = uilabel(app.DiabetesTab);
            app.SkinThicknessEditFieldLabel.Text = 'Skin Thickness';
            app.SkinThicknessEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.BloodPressureEditFieldLabel = uilabel(app.DiabetesTab);
            app.BloodPressureEditFieldLabel.Text = 'Blood Pressure';
            app.BloodPressureEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.GlucoseEditFieldLabel = uilabel(app.DiabetesTab);
            app.GlucoseEditFieldLabel.Text = 'Glucose';
            app.GlucoseEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.InsulinEditFieldLabel = uilabel(app.DiabetesTab);
            app.InsulinEditFieldLabel.Text = 'Insulin';
            app.InsulinEditField = uieditfield(app.DiabetesTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiagnosisSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end