classdef DiabetesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AgeEditField                 matlab.ui.control.NumericEditField
        AgeEditFieldLabel            matlab.ui.control.Label
        BMIEditField                 matlab.ui.control.NumericEditField
        BMIEditFieldLabel            matlab.ui.control.Label
        InsulinEditField             matlab.ui.control.NumericEditField
        InsulinEditFieldLabel        matlab.ui.control.Label
        SkinThicknessEditField       matlab.ui.control.NumericEditField
        SkinThicknessEditFieldLabel  matlab.ui.control.Label
        BloodPressureEditField       matlab.ui.control.NumericEditField
        BloodPressureEditFieldLabel  matlab.ui.control.Label
        GlucoseEditField             matlab.ui.control.NumericEditField
        GlucoseEditFieldLabel        matlab.ui.control.Label
        PregnanciesEditField         matlab.ui.control.NumericEditField
        PregnanciesEditFieldLabel    matlab.ui.control.Label
        PredictButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            % === Parsing for diabetes diagnosis ===
            Pregnancies = [app.PregnanciesEditField.Value];
            Glucose = [app.GlucoseEditField.Value];
            BloodPressure = [app.BloodPressureEditField.Value];
            SkinThickness = [app.SkinThicknessEditField.Value];
            Insulin = [app.InsulinEditField.Value];
            BMI = [app.BMIEditField.Value];
            Age = [app.AgeEditField.Value];
            
            % Table for diagnosis
            input = table(Pregnancies, Glucose, BloodPressure, SkinThickness, Insulin, BMI, Age);

            % === Run predictions and send output to PredictionsApp ===
            diagnosis = DiabetesCommunicator.returnPrediction(input);
            info = DiabetesCommunicator.returnInfo(input);
            inputStr = SuperCommunicator.inputToStringArray(input);
            unhealthy = SuperCommunicator.printUnhealthyCategories(input, DiabetesCommunicator.getUnhealthyValues(input));
            myValues = [inputStr newline "Your unhealthy values:" unhealthy];
            PredictionsApp(diagnosis,info,myValues, 'Diabetes');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.PregnanciesEditFieldLabel = uilabel(app.UIFigure);
            app.PregnanciesEditFieldLabel.Tooltip = {'Number of pregnancies.'};
            app.PregnanciesEditFieldLabel.Text = 'Pregnancies';
            app.PregnanciesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PregnanciesEditField.Limits = [0 Inf];
            app.PregnanciesEditField.ValueDisplayFormat = '%.0f';
            app.PregnanciesEditField.Tooltip = {''};
            app.GlucoseEditFieldLabel = uilabel(app.UIFigure);
            app.GlucoseEditFieldLabel.Tooltip = {'The amount of glucose in the blood in mg/dL.'};
            app.GlucoseEditFieldLabel.Text = 'Glucose';
            app.GlucoseEditField = uieditfield(app.UIFigure, 'numeric');
            app.GlucoseEditField.Limits = [0 Inf];
            app.BloodPressureEditFieldLabel = uilabel(app.UIFigure);
            app.BloodPressureEditFieldLabel.Tooltip = {'The pressure of blood in a blood vessel in mmHg.'};
            app.BloodPressureEditFieldLabel.Text = 'Blood Pressure';
            app.BloodPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.BloodPressureEditField.Limits = [0 Inf];
            app.SkinThicknessEditFieldLabel = uilabel(app.UIFigure);
            app.SkinThicknessEditFieldLabel.Tooltip = {'Triceps skin fold thickness (mm)'};
            app.SkinThicknessEditFieldLabel.Text = 'Skin Thickness';
            app.SkinThicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.SkinThicknessEditField.Limits = [0 Inf];
            app.SkinThicknessEditField.Tooltip = {''};
            app.InsulinEditFieldLabel = uilabel(app.UIFigure);
            app.InsulinEditFieldLabel.Tooltip = {'Insulin is an anabolic hormone that helps regulate blood sugar levels.'};
            app.InsulinEditFieldLabel.Text = 'Insulin';
            app.InsulinEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsulinEditField.Limits = [0 Inf];
            app.BMIEditFieldLabel = uilabel(app.UIFigure);
            app.BMIEditFieldLabel.Tooltip = {'The Body Mass Index is calculated by dividing weight (kg) by height'; 'squared (m^2).'};
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.UIFigure, 'numeric');
            app.BMIEditField.Limits = [0 Inf];
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Tooltip = {'Age of the person in years.'};
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgeEditField.Limits = [0 Inf];
            app.AgeEditField.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiabetesApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end