classdef DiagnosisSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        SymptomsListBox                 matlab.ui.control.ListBox
        SymptomsListBoxLabel            matlab.ui.control.Label
        SelectedSymptomsListBox         matlab.ui.control.ListBox
        SelectedSymptomsListBoxLabel    matlab.ui.control.Label
        DiseaseTextArea                 matlab.ui.control.TextArea
        DiseaseTextAreaLabel            matlab.ui.control.Label
        General_PredictButton           matlab.ui.control.Button
        HeartDiseaseRiskTab             matlab.ui.container.Tab
        DiagnosisEditField              matlab.ui.control.EditField
        DiagnosisEditFieldLabel         matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        AgeEditField_3                  matlab.ui.control.NumericEditField
        AgeEditField_3Label             matlab.ui.control.Label
        SexDropDown_2                   matlab.ui.control.DropDown
        SexDropDown_2Label              matlab.ui.control.Label
        HD_PredictButton                matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        STslopeEditField                matlab.ui.control.NumericEditField
        STslopeEditFieldLabel           matlab.ui.control.Label
        STdepressionEditField           matlab.ui.control.NumericEditField
        STdepressionEditFieldLabel      matlab.ui.control.Label
        ExerciseinducedanginaDropDown   matlab.ui.control.DropDown
        ExerciseinducedanginaDropDownLabel  matlab.ui.control.Label
        MaximumheartrateachievedEditField  matlab.ui.control.NumericEditField
        MaximumheartrateachievedEditFieldLabel  matlab.ui.control.Label
        RestingelectrocardiographicresultsSpinner  matlab.ui.control.Spinner
        RestingelectrocardiographicresultsSpinnerLabel  matlab.ui.control.Label
        MajorvesselscoloredbyfluoroscopySpinner  matlab.ui.control.Spinner
        MajorvesselscoloredbyfluoroscopySpinnerLabel  matlab.ui.control.Label
        ThalassemiaDropDown             matlab.ui.control.DropDown
        ThalassemiaDropDownLabel        matlab.ui.control.Label
        Fastingbloodsugar120mgdlDropDown  matlab.ui.control.DropDown
        Fastingbloodsugar120mgdlDropDownLabel  matlab.ui.control.Label
        SerumCholesterolmgdlEditField   matlab.ui.control.NumericEditField
        SerumCholesterolmgdlEditFieldLabel  matlab.ui.control.Label
        ChestPainSpinner                matlab.ui.control.Spinner
        ChestPainSpinnerLabel           matlab.ui.control.Label
        RestingbloodpressureEditField   matlab.ui.control.NumericEditField
        RestingbloodpressureEditFieldLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        HistoryofdiabetesDropDown       matlab.ui.control.DropDown
        HistoryofdiabetesDropDownLabel  matlab.ui.control.Label
        GeneralhealthDropDown           matlab.ui.control.DropDown
        GeneralhealthDropDownLabel      matlab.ui.control.Label
        DaysMonthofgoodphysicalhealthSpinner  matlab.ui.control.Spinner
        DaysMonthofgoodphysicalhealthSpinnerLabel  matlab.ui.control.Label
        DaysMonthofgoodmentalhealthSpinnerLabel  matlab.ui.control.Label
        DaysMonthofgoodmentalhealthSpinner  matlab.ui.control.Spinner
        BMIEditField_2                  matlab.ui.control.NumericEditField
        BMIEditField_2Label             matlab.ui.control.Label
        HistoyofstrokeCheckBox          matlab.ui.control.CheckBox
        DifficultywalkingorclimbingstairsCheckBox  matlab.ui.control.CheckBox
        PhysicalactivitiyduringpastmonthCheckBox  matlab.ui.control.CheckBox
        Heavydrinking14weeklymale7weeklyfemaleCheckBox  matlab.ui.control.CheckBox
        Smokedmorethan100cigarettesCheckBox  matlab.ui.control.CheckBox
        HistoyofhighbloodpressureCheckBox  matlab.ui.control.CheckBox
        HistoyofhighbloodcholesterolCheckBox  matlab.ui.control.CheckBox
        DiabetesTab                     matlab.ui.container.Tab
        ResultTextArea                  matlab.ui.control.TextArea
        ResultTextAreaLabel             matlab.ui.control.Label
        Diabetes_PredictButton          matlab.ui.control.Button
        InsulinEditField                matlab.ui.control.NumericEditField
        InsulinEditFieldLabel           matlab.ui.control.Label
        GlucoseEditField                matlab.ui.control.NumericEditField
        GlucoseEditFieldLabel           matlab.ui.control.Label
        BloodPressureEditField          matlab.ui.control.NumericEditField
        BloodPressureEditFieldLabel     matlab.ui.control.Label
        SkinThicknessEditField          matlab.ui.control.NumericEditField
        SkinThicknessEditFieldLabel     matlab.ui.control.Label
        BMIEditField                    matlab.ui.control.NumericEditField
        BMIEditFieldLabel               matlab.ui.control.Label
        NumberofPregnaciesEditField     matlab.ui.control.NumericEditField
        NumberofPregnaciesEditFieldLabel  matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        LumpySkinDiseaseTab             matlab.ui.container.Tab
        PickCoordinatesButton           matlab.ui.control.Button
        LongitudeSpinner                matlab.ui.control.Spinner
        LatitudeSpinner                 matlab.ui.control.Spinner
        VaporPressurepsiEditField       matlab.ui.control.NumericEditField
        VaporPressurepsiEditFieldLabel  matlab.ui.control.Label
        ResultTextArea_2                matlab.ui.control.TextArea
        ResultTextArea_2Label           matlab.ui.control.Label
        PredictButton                   matlab.ui.control.StateButton
        CloudcoverEditField             matlab.ui.control.NumericEditField
        CloudcoverEditFieldLabel        matlab.ui.control.Label
        DominantlandcoverSpinner        matlab.ui.control.Spinner
        DominantlandcoverSpinnerLabel   matlab.ui.control.Label
        FrostydayfrequencyEditField     matlab.ui.control.NumericEditField
        FrostydayfrequencyEditFieldLabel  matlab.ui.control.Label
        WetdayfrequencyEditField        matlab.ui.control.NumericEditField
        WetdayfrequencyEditFieldLabel   matlab.ui.control.Label
        PrecipitationcmEditField        matlab.ui.control.NumericEditField
        PrecipitationcmEditFieldLabel   matlab.ui.control.Label
        DailyTemperatureCLabel          matlab.ui.control.Label
        LongitudeGauge                  matlab.ui.control.SemicircularGauge
        LongitudeGaugeLabel             matlab.ui.control.Label
        LatitudeGauge                   matlab.ui.control.SemicircularGauge
        LatitudeGaugeLabel              matlab.ui.control.Label
        MeanMaxSlider                   matlab.ui.control.Slider
        MeanMaxSliderLabel              matlab.ui.control.Label
        MeanSlider                      matlab.ui.control.Slider
        MeanSliderLabel                 matlab.ui.control.Label
        MeanMinSlider                   matlab.ui.control.Slider
        MeanMinSliderLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        mySymptoms
        myModel
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mySymptoms = readtable(['Project' filesep 'ML' filesep 'General' filesep 'Symptom-severity.csv']); % mySymptoms is a table with variables "Symptom" and "weight"
            app.SymptomsListBox.Items = app.mySymptoms.Symptom; % display array extracted from "Symptom" variable
        end

        % Button pushed function: General_PredictButton
        function General_PredictButtonPushed(app, event)
            selectedArray = app.SymptomsListBox.Value;
            diagnosis = GeneralCommunicator.returnPrediction(selectedArray);
            app.DiseaseTextArea.Value = diagnosis;
        end

        % Button pushed function: HD_PredictButton
        function HD_PredictButtonPushed(app, event)
            
            %Parsing for heart disease risk
            HighBP = [app.HistoyofhighbloodpressureCheckBox.Value];
            HighChol = [app.HistoyofhighbloodcholesterolCheckBox.Value];
            BMI = [app.BMIEditField_2.Value];
            Smoker = [app.Smokedmorethan100cigarettesCheckBox.Value];
            Stroke = [app.HistoyofstrokeCheckBox.Value];
            %Diabetes = [app.HistoryofdiabetesDropDown.Value];
            switch app.HistoryofdiabetesDropDown.Value 
                case "No"
                    Diabetes = [0];
                case "Yes, during pregnancy"
                    Diabetes = [1];
                case "Yes"
                    Diabetes = [2];
                otherwise
                    Diabetes = [0];
            end
            PhysActivity = [app.DaysMonthofgoodphysicalhealthSpinner.Value];
            HvyAlcoholConsump = [app.Heavydrinking14weeklymale7weeklyfemaleCheckBox.Value];
            %GenHlth = [app.GeneralhealthDropDown.Value];
            switch app.GeneralhealthDropDown.Value
                case "Excellent"
                    GenHlth = [1];
                case "Very good"
                    GenHlth = [2];
                case "Good"
                    GenHlth = [3];
                case "Fair"
                    GenHlth = [4];
                case "Poor"
                    GenHlth = [5];
                otherwise
                    GenHlth = [3];
            end
            MentHlth = [app.DaysMonthofgoodmentalhealthSpinner.Value];
            PhysHlth = [app.DaysMonthofgoodphysicalhealthSpinner.Value];
            DiffWalk = [app.DifficultywalkingorclimbingstairsCheckBox.Value];
            %Sex = [app.SexDropDown_2.Value];
            switch app.SexDropDown_2.Value
                case "Male"
                    Sex = [0];
                case "Female"
                    Sex = [1];
                otherwise
                    Sex = [0];
            end
            %Age = [app.AgeDropDown.Value];
            switch true
                case app.AgeEditField_3.Value>=18 && app.AgeEditField_3.Value<=24
                    Age = [1];
                case app.AgeEditField_3.Value>=25 && app.AgeEditField_3.Value<=29
                    Age = [2];
                case app.AgeEditField_3.Value>=30 && app.AgeEditField_3.Value<=34
                    Age = [3];
                case app.AgeEditField_3.Value>=35 && app.AgeEditField_3.Value<=39
                    Age = [4];
                case app.AgeEditField_3.Value>=40 && app.AgeEditField_3.Value<=44
                    Age = [5];
                case app.AgeEditField_3.Value>=45 && app.AgeEditField_3.Value<=49
                    Age = [6];
                case app.AgeEditField_3.Value>=50 && app.AgeEditField_3.Value<=54
                    Age = [7];
                case app.AgeEditField_3.Value>=55 && app.AgeEditField_3.Value<=59
                    Age = [8];
                case app.AgeEditField_3.Value>=60 && app.AgeEditField_3.Value<=64
                    Age = [9];
                case app.AgeEditField_3.Value>=65 && app.AgeEditField_3.Value<=69
                    Age = [10];
                case app.AgeEditField_3.Value>=70 && app.AgeEditField_3.Value<=74
                    Age = [11];
                case app.AgeEditField_3.Value>=75 && app.AgeEditField_3.Value<=79
                    Age = [12];
                case app.AgeEditField_3.Value>=80
                    Age = [13];
                otherwise
                    Age = [14];
            end

            %Table for riskFactors
            indicators = table(HighBP, HighChol, BMI, Smoker, Stroke, Diabetes, ...
                PhysActivity, HvyAlcoholConsump, GenHlth, MentHlth, PhysHlth, DiffWalk, Sex, Age);

            %Second tab for heart disease diagnosis
            age = [app.AgeEditField_3.Value];
            %sex = [app.SexDropDown.Value];
            switch app.SexDropDown_2.Value
                case "Male"
                    sex = [0];
                case "Female"
                    sex = [1];
                otherwise
                    sex = [0];
            end
            cp = [app.ChestPainSpinner.Value];
            trestbps = [app.RestingbloodpressureEditField.Value];
            chol = [app.SerumCholesterolmgdlEditField.Value];
            %fbs = [app.Fastingbloodsugar120mgdlDropDown.Value];
            switch app.ThalassemiaDropDown.Value
                case "No"
                    fbs = [0];
                case "Yes"
                    fbs = [1];
                otherwise
                    fbs = [0];
            end
            restecg = [app.RestingelectrocardiographicresultsSpinner.Value];
            thalach = [app.MaximumheartrateachievedEditField.Value];
            %exang = [];
            switch app.ExerciseinducedanginaDropDown.Value
                case "No"
                    exang = [0];
                case "Yes"
                    exang = [1];
                otherwise
                    exang = [0];
            end
            oldpeak = [app.STdepressionEditField.Value];
            slope = [app.STslopeEditField.Value];
            ca = [app.MajorvesselscoloredbyfluoroscopySpinner.Value];
            %thal = [app.ThalassemiaDropDown.Value];
            switch app.ThalassemiaDropDown.Value
                case "Normal"
                    thal = [0];
                case "Fixed defect"
                    thal = [1];
                case "Reversible defect"
                    thal = [2];
                otherwise
                    thal = [0];
            end
            %Table for diagnosis
            levels = table(age, sex, cp, trestbps, chol, fbs, restecg, thalach, exang, oldpeak, slope, ca, thal);
            
            %Array with both tables of values [riskFactors,diagnosis];
            %input = {indicators,levels};

            %%Testing
            %table1 = input(1,1);
            %element1 = table1{1,1} ;
            %app.TestEditField.Value = element1{1,"Age"} ;

            %%Result
            %app.TestEditField.Value = [indicators,diagnosis];
            diagnosis = HeartDiseaseCommunicator.returnPrediction(levels, indicators);
            app.DiagnosisEditField.Value = diagnosis;


        end

        % Button pushed function: Diabetes_PredictButton
        function Diabetes_PredictButtonPushed(app, event)
            Pregnancies = [app.NumberofPregnaciesEditField.Value];
            Glucose = [app.GlucoseEditField.Value];
            BloodPressure = [app.BloodPressureEditField.Value];
            SkinThickness = [app.SkinThicknessEditField.Value];
            Insulin = [app.InsulinEditField.Value];
            BMI = [app.BMIEditField.Value];
            Age = [app.AgeEditField.Value];
            
            input = table(Pregnancies, Glucose, BloodPressure, SkinThickness, Insulin, BMI, Age);
            diagnosis = DiabetesCommunicator.returnPrediction(input);
            app.ResultTextArea.Value = diagnosis;
        end

        % Value changed function: SymptomsListBox
        function SymptomsListBoxValueChanged(app, event)
            selected = app.SymptomsListBox.Value;
            app.SelectedSymptomsListBox.Items = selected;
        end

        % Value changed function: PredictButton
        function PredictButtonValueChanged(app, event)

            %Parameters fro lumpy skin
            x = [app.LongitudeSpinner.Value];
            y = [app.LatitudeSpinner.Value];

            cld = [app.CloudcoverEditField.Value];
            frs = [app.FrostydayfrequencyEditField.Value];
            pre = [app.PrecipitationcmEditField.Value];

            tmn = [app.MeanMinSlider.Value];
            tmp = [app.MeanSlider.Value];
            tmx = [app.MeanMaxSlider];

            vap = [app.VaporPressurepsiEditField.Value];
            wet = [app.WetdayfrequencyEditField.Value];
            
            dominant_land_cover = [app.DominantlandcoverSpinner.Value];

            %Table for diagnosis
            levels = table(cld, dominant_land_cover, frs, pre, tmn, tmp, tmx, vap, wet,  x, y);

            %%Result
            %diagnosis = DiabetesCommunicator.returnPrediction(input);
            app.ResultTextArea_2.Value = sprintf('%.6f',levels{1, "x"});
            %app.ResultTextArea_2.Value = levels{1, "cld"} ;

        end

        % Value changed function: LatitudeSpinner
        function LatitudeSpinnerValueChanged(app, event)

            app.LatitudeGauge.Value = app.LatitudeSpinner.Value;
            
        end

        % Value changed function: LongitudeSpinner
        function LongitudeSpinnerValueChanged(app, event)
            
            
        end

        % Button pushed function: PickCoordinatesButton
        function PickCoordinatesButtonPushed(app, event)
            [lat, lon]= map();
            app.LatitudeGauge.Value = lat;
            app.LongitudeGauge.Value = lon;
            app.LongitudeSpinner.Value = lon;
            app.LatitudeSpinner.Value = lat;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.General_PredictButton = uibutton(app.GeneralTab, 'push');
            app.General_PredictButton.ButtonPushedFcn = createCallbackFcn(app, @General_PredictButtonPushed, true);
            app.General_PredictButton.Text = 'Predict';
            app.DiseaseTextAreaLabel = uilabel(app.GeneralTab);
            app.DiseaseTextAreaLabel.Text = 'Disease';
            app.DiseaseTextArea = uitextarea(app.GeneralTab);
            app.SelectedSymptomsListBoxLabel = uilabel(app.GeneralTab);
            app.SelectedSymptomsListBoxLabel.Text = 'Selected Symptoms';
            app.SelectedSymptomsListBox = uilistbox(app.GeneralTab);
            app.SelectedSymptomsListBox.Items = {};
            app.SelectedSymptomsListBox.Value = {};
            app.SymptomsListBoxLabel = uilabel(app.GeneralTab);
            app.SymptomsListBoxLabel.Text = 'Symptoms';
            app.SymptomsListBox = uilistbox(app.GeneralTab);
            app.SymptomsListBox.Items = {'A, B, C'};
            app.SymptomsListBox.Multiselect = 'on';
            app.SymptomsListBox.ValueChangedFcn = createCallbackFcn(app, @SymptomsListBoxValueChanged, true);
            app.SymptomsListBox.Value = {};
            app.HeartDiseaseRiskTab = uitab(app.TabGroup);
            app.HeartDiseaseRiskTab.Title = 'Heart Disease Risk';
            app.Panel = uipanel(app.HeartDiseaseRiskTab);
            app.HistoyofhighbloodcholesterolCheckBox = uicheckbox(app.Panel);
            app.HistoyofhighbloodcholesterolCheckBox.Text = 'Histoy of high blood cholesterol.';
            app.HistoyofhighbloodpressureCheckBox = uicheckbox(app.Panel);
            app.HistoyofhighbloodpressureCheckBox.Text = 'Histoy of high blood pressure.';
            app.Smokedmorethan100cigarettesCheckBox = uicheckbox(app.Panel);
            app.Smokedmorethan100cigarettesCheckBox.Text = 'Smoked more than 100 cigarettes.';
            app.Heavydrinking14weeklymale7weeklyfemaleCheckBox = uicheckbox(app.Panel);
            app.Heavydrinking14weeklymale7weeklyfemaleCheckBox.Text = 'Heavy drinking (14 weekly male, 7 weekly female)';
            app.PhysicalactivitiyduringpastmonthCheckBox = uicheckbox(app.Panel);
            app.PhysicalactivitiyduringpastmonthCheckBox.Text = 'Physical activitiy during past month.';
            app.DifficultywalkingorclimbingstairsCheckBox = uicheckbox(app.Panel);
            app.DifficultywalkingorclimbingstairsCheckBox.Text = 'Difficulty walking or climbing stairs.';
            app.HistoyofstrokeCheckBox = uicheckbox(app.Panel);
            app.HistoyofstrokeCheckBox.Text = 'Histoy of stroke.';
            app.BMIEditField_2Label = uilabel(app.Panel);
            app.BMIEditField_2Label.Text = 'BMI';
            app.BMIEditField_2 = uieditfield(app.Panel, 'numeric');
            app.BMIEditField_2.Limits = [0 999];
            app.DaysMonthofgoodmentalhealthSpinner = uispinner(app.Panel);
            app.DaysMonthofgoodmentalhealthSpinner.Limits = [0 30];
            app.DaysMonthofgoodmentalhealthSpinnerLabel = uilabel(app.Panel);
            app.DaysMonthofgoodmentalhealthSpinnerLabel.Text = 'Days/Month of good mental health.';
            app.DaysMonthofgoodphysicalhealthSpinnerLabel = uilabel(app.Panel);
            app.DaysMonthofgoodphysicalhealthSpinnerLabel.Text = 'Days/Month of good physical health.';
            app.DaysMonthofgoodphysicalhealthSpinner = uispinner(app.Panel);
            app.DaysMonthofgoodphysicalhealthSpinner.Limits = [0 30];
            app.GeneralhealthDropDownLabel = uilabel(app.Panel);
            app.GeneralhealthDropDownLabel.Text = 'General health';
            app.GeneralhealthDropDown = uidropdown(app.Panel);
            app.GeneralhealthDropDown.Items = {'none', 'Excellent', 'Very good', 'Good', 'Fair', 'Poor'};
            app.GeneralhealthDropDown.Value = 'none';
            app.HistoryofdiabetesDropDownLabel = uilabel(app.Panel);
            app.HistoryofdiabetesDropDownLabel.Text = 'History of diabetes';
            app.HistoryofdiabetesDropDown = uidropdown(app.Panel);
            app.HistoryofdiabetesDropDown.Items = {'No', 'Yes, during pregnancy', 'Yes'};
            app.HistoryofdiabetesDropDown.Value = 'No';
            app.Panel_2 = uipanel(app.HeartDiseaseRiskTab);
            app.RestingbloodpressureEditFieldLabel = uilabel(app.Panel_2);
            app.RestingbloodpressureEditFieldLabel.Text = 'Resting blood pressure';
            app.RestingbloodpressureEditField = uieditfield(app.Panel_2, 'numeric');
            app.ChestPainSpinnerLabel = uilabel(app.Panel_2);
            app.ChestPainSpinnerLabel.Text = 'Chest Pain';
            app.ChestPainSpinner = uispinner(app.Panel_2);
            app.ChestPainSpinner.Limits = [0 4];
            app.SerumCholesterolmgdlEditFieldLabel = uilabel(app.Panel_2);
            app.SerumCholesterolmgdlEditFieldLabel.Text = 'Serum Cholesterol (mg/dl)';
            app.SerumCholesterolmgdlEditField = uieditfield(app.Panel_2, 'numeric');
            app.Fastingbloodsugar120mgdlDropDownLabel = uilabel(app.Panel_2);
            app.Fastingbloodsugar120mgdlDropDownLabel.Text = 'Fasting blood sugar > 120 mg/dl';
            app.Fastingbloodsugar120mgdlDropDown = uidropdown(app.Panel_2);
            app.Fastingbloodsugar120mgdlDropDown.Items = {'No', 'Yes'};
            app.Fastingbloodsugar120mgdlDropDown.Value = 'No';
            app.ThalassemiaDropDownLabel = uilabel(app.Panel_2);
            app.ThalassemiaDropDownLabel.Text = 'Thalassemia';
            app.ThalassemiaDropDown = uidropdown(app.Panel_2);
            app.ThalassemiaDropDown.Items = {'Normal', 'FIxed defect', 'Reversible defect'};
            app.ThalassemiaDropDown.Value = 'Normal';
            app.MajorvesselscoloredbyfluoroscopySpinnerLabel = uilabel(app.Panel_2);
            app.MajorvesselscoloredbyfluoroscopySpinnerLabel.Text = 'Major vessels colored by fluoroscopy ';
            app.MajorvesselscoloredbyfluoroscopySpinner = uispinner(app.Panel_2);
            app.MajorvesselscoloredbyfluoroscopySpinner.Limits = [0 4];
            app.RestingelectrocardiographicresultsSpinnerLabel = uilabel(app.Panel_2);
            app.RestingelectrocardiographicresultsSpinnerLabel.Text = 'Resting electrocardiographic results';
            app.RestingelectrocardiographicresultsSpinner = uispinner(app.Panel_2);
            app.RestingelectrocardiographicresultsSpinner.Limits = [0 2];
            app.MaximumheartrateachievedEditFieldLabel = uilabel(app.Panel_2);
            app.MaximumheartrateachievedEditFieldLabel.Text = 'Maximum heart rate achieved';
            app.MaximumheartrateachievedEditField = uieditfield(app.Panel_2, 'numeric');
            app.ExerciseinducedanginaDropDownLabel = uilabel(app.Panel_2);
            app.ExerciseinducedanginaDropDownLabel.Text = 'Exercise induced angina';
            app.ExerciseinducedanginaDropDown = uidropdown(app.Panel_2);
            app.ExerciseinducedanginaDropDown.Items = {'No', 'Yes'};
            app.ExerciseinducedanginaDropDown.Value = 'No';
            app.STdepressionEditFieldLabel = uilabel(app.Panel_2);
            app.STdepressionEditFieldLabel.Text = 'ST depression';
            app.STdepressionEditField = uieditfield(app.Panel_2, 'numeric');
            app.STslopeEditFieldLabel = uilabel(app.Panel_2);
            app.STslopeEditFieldLabel.Text = 'ST slope';
            app.STslopeEditField = uieditfield(app.Panel_2, 'numeric');
            app.HD_PredictButton = uibutton(app.HeartDiseaseRiskTab, 'push');
            app.HD_PredictButton.ButtonPushedFcn = createCallbackFcn(app, @HD_PredictButtonPushed, true);
            app.HD_PredictButton.Text = 'Predict';
            app.Panel_3 = uipanel(app.HeartDiseaseRiskTab);
            app.SexDropDown_2Label = uilabel(app.Panel_3);
            app.SexDropDown_2Label.Text = 'Sex';
            app.SexDropDown_2 = uidropdown(app.Panel_3);
            app.SexDropDown_2.Items = {'Male', 'Female'};
            app.SexDropDown_2.Value = 'Male';
            app.AgeEditField_3Label = uilabel(app.Panel_3);
            app.AgeEditField_3Label.Text = 'Age';
            app.AgeEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.AgeEditField_3.Limits = [0 200];
            app.DiagnosisEditFieldLabel = uilabel(app.HeartDiseaseRiskTab);
            app.DiagnosisEditFieldLabel.Text = 'Diagnosis';
            app.DiagnosisEditField = uieditfield(app.HeartDiseaseRiskTab, 'text');
            app.DiabetesTab = uitab(app.TabGroup);
            app.DiabetesTab.Title = 'Diabetes';
            app.AgeEditFieldLabel = uilabel(app.DiabetesTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.NumberofPregnaciesEditFieldLabel = uilabel(app.DiabetesTab);
            app.NumberofPregnaciesEditFieldLabel.Text = 'Number of Pregnacies';
            app.NumberofPregnaciesEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.BMIEditFieldLabel = uilabel(app.DiabetesTab);
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.SkinThicknessEditFieldLabel = uilabel(app.DiabetesTab);
            app.SkinThicknessEditFieldLabel.Text = 'Skin Thickness';
            app.SkinThicknessEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.BloodPressureEditFieldLabel = uilabel(app.DiabetesTab);
            app.BloodPressureEditFieldLabel.Text = 'Blood Pressure';
            app.BloodPressureEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.GlucoseEditFieldLabel = uilabel(app.DiabetesTab);
            app.GlucoseEditFieldLabel.Text = 'Glucose';
            app.GlucoseEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.InsulinEditFieldLabel = uilabel(app.DiabetesTab);
            app.InsulinEditFieldLabel.Text = 'Insulin';
            app.InsulinEditField = uieditfield(app.DiabetesTab, 'numeric');
            app.Diabetes_PredictButton = uibutton(app.DiabetesTab, 'push');
            app.Diabetes_PredictButton.ButtonPushedFcn = createCallbackFcn(app, @Diabetes_PredictButtonPushed, true);
            app.Diabetes_PredictButton.Text = 'Predict';
            app.ResultTextAreaLabel = uilabel(app.DiabetesTab);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.DiabetesTab);
            app.LumpySkinDiseaseTab = uitab(app.TabGroup);
            app.LumpySkinDiseaseTab.Title = 'Lumpy Skin Disease';
            app.MeanMinSliderLabel = uilabel(app.LumpySkinDiseaseTab);
            app.MeanMinSliderLabel.Text = 'Mean Min ';
            app.MeanMinSlider = uislider(app.LumpySkinDiseaseTab);
            app.MeanSliderLabel = uilabel(app.LumpySkinDiseaseTab);
            app.MeanSliderLabel.Text = 'Mean ';
            app.MeanSlider = uislider(app.LumpySkinDiseaseTab);
            app.MeanMaxSliderLabel = uilabel(app.LumpySkinDiseaseTab);
            app.MeanMaxSliderLabel.Text = 'Mean Max ';
            app.MeanMaxSlider = uislider(app.LumpySkinDiseaseTab);
            app.LatitudeGaugeLabel = uilabel(app.LumpySkinDiseaseTab);
            app.LatitudeGaugeLabel.Text = 'Latitude';
            app.LatitudeGauge = uigauge(app.LumpySkinDiseaseTab, 'semicircular');
            app.LatitudeGauge.Limits = [-90 90];
            app.LongitudeGaugeLabel = uilabel(app.LumpySkinDiseaseTab);
            app.LongitudeGaugeLabel.Text = 'Longitude';
            app.LongitudeGauge = uigauge(app.LumpySkinDiseaseTab, 'semicircular');
            app.LongitudeGauge.Limits = [0 180];
            app.LongitudeGauge.ScaleDirection = 'counterclockwise';
            app.DailyTemperatureCLabel = uilabel(app.LumpySkinDiseaseTab);
            app.DailyTemperatureCLabel.Text = 'Daily Temperature C°';
            app.PrecipitationcmEditFieldLabel = uilabel(app.LumpySkinDiseaseTab);
            app.PrecipitationcmEditFieldLabel.Text = 'Precipitation (cm)';
            app.PrecipitationcmEditField = uieditfield(app.LumpySkinDiseaseTab, 'numeric');
            app.WetdayfrequencyEditFieldLabel = uilabel(app.LumpySkinDiseaseTab);
            app.WetdayfrequencyEditFieldLabel.Text = 'Wet day frequency';
            app.WetdayfrequencyEditField = uieditfield(app.LumpySkinDiseaseTab, 'numeric');
            app.WetdayfrequencyEditField.Limits = [0 31];
            app.FrostydayfrequencyEditFieldLabel = uilabel(app.LumpySkinDiseaseTab);
            app.FrostydayfrequencyEditFieldLabel.Text = 'Frosty day frequency';
            app.FrostydayfrequencyEditField = uieditfield(app.LumpySkinDiseaseTab, 'numeric');
            app.FrostydayfrequencyEditField.Limits = [0 31];
            app.DominantlandcoverSpinnerLabel = uilabel(app.LumpySkinDiseaseTab);
            app.DominantlandcoverSpinnerLabel.Text = 'Dominant land cover';
            app.DominantlandcoverSpinner = uispinner(app.LumpySkinDiseaseTab);
            app.DominantlandcoverSpinner.Limits = [0 11];
            app.CloudcoverEditFieldLabel = uilabel(app.LumpySkinDiseaseTab);
            app.CloudcoverEditFieldLabel.Text = 'Cloud cover';
            app.CloudcoverEditField = uieditfield(app.LumpySkinDiseaseTab, 'numeric');
            app.PredictButton = uibutton(app.LumpySkinDiseaseTab, 'state');
            app.PredictButton.ValueChangedFcn = createCallbackFcn(app, @PredictButtonValueChanged, true);
            app.PredictButton.Text = 'Predict';
            app.ResultTextArea_2Label = uilabel(app.LumpySkinDiseaseTab);
            app.ResultTextArea_2Label.Text = 'Result';
            app.ResultTextArea_2 = uitextarea(app.LumpySkinDiseaseTab);
            app.VaporPressurepsiEditFieldLabel = uilabel(app.LumpySkinDiseaseTab);
            app.VaporPressurepsiEditFieldLabel.Text = 'Vapor Pressure (psi)';
            app.VaporPressurepsiEditField = uieditfield(app.LumpySkinDiseaseTab, 'numeric');
            app.LatitudeSpinner = uispinner(app.LumpySkinDiseaseTab);
            app.LatitudeSpinner.Limits = [-90 90];
            app.LatitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @LatitudeSpinnerValueChanged, true);
            app.LongitudeSpinner = uispinner(app.LumpySkinDiseaseTab);
            app.LongitudeSpinner.Limits = [0 180];
            app.LongitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @LongitudeSpinnerValueChanged, true);
            app.PickCoordinatesButton = uibutton(app.LumpySkinDiseaseTab, 'push');
            app.PickCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @PickCoordinatesButtonPushed, true);
            app.PickCoordinatesButton.Text = 'Pick Coordinates';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiagnosisSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end