classdef GeneralApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SearchField                   matlab.ui.control.EditField
        PredictButton                 matlab.ui.control.Button
        SelectedSymptomsListBox       matlab.ui.control.ListBox
        SelectedSymptomsListBoxLabel  matlab.ui.control.Label
        SymptomsListBox               matlab.ui.control.ListBox
        SymptomsListBoxLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        symptoms %symptoms from dataset (cell array)
        selected = {} %selected symptoms (celle array)
        search = ""
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % read table with variables "Symptom" and "weight" and extract "Symptom" as array
            app.symptoms = readtable(['Project' filesep 'ML' filesep 'General' filesep 'datasets' filesep 'Symptom-severity.csv']).Symptom; 
            % delete 'prognosis' item
            app.symptoms(end) = []; 
            % display symptoms array
            app.SymptomsListBox.Items = app.symptoms; 
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            % returns [prediction, descriptionDisease]
            diagnosis = GeneralCommunicator.returnPrediction(app.selected);
            % diagnosis(1) = prediction
            info = GeneralCommunicator.returnInfo(diagnosis(1));
            % have no symptoms been selected?
            if isempty(app.selected)
                app.selected = "No symptoms have been selected.";
            end
            % open PredictionsApp with results
            PredictionsApp(diagnosis,info,app.selected, 'General');
            app.selected = {};
        end

        % Value changed function: SymptomsListBox
        function SymptomsListBoxValueChanged(app, event)
            % add user selection to SelectedSymptoms
            value = app.SymptomsListBox.Value;
            app.selected(end+1) = {value};
            app.SelectedSymptomsListBox.Items = app.selected;
            app.SymptomsListBox.Value = {};

            % delete user selection from currentSymptoms (Symptoms may be affected by search)
            currentSymptoms = app.SymptomsListBox.Items;
            currentSymptoms(strcmp(currentSymptoms,value)) = [];
            app.SymptomsListBox.Items = currentSymptoms;

            % delete user selection from symptoms
            app.symptoms(strcmp(app.symptoms,value)) = [];
        end

        % Value changed function: SearchField
        function SearchFieldValueChanged(app, event)
                % array with all search words
                app.search = split(app.SearchField.Value); 
                % 2 letter search words are too common so delete them
                app.search = app.search(app.search == "" | strlength(app.search) > 2); 
                % array with all symptoms that match (at least) one search word
                found = app.symptoms(contains(app.symptoms,app.search)); 
                % display all found symptoms
                app.SymptomsListBox.Items = found;
        end

        % Value changed function: SelectedSymptomsListBox
        function SelectedSymptomsListBoxValueChanged(app, event)
            % save user deletion and delete from SelectedSymptoms
            value = app.SelectedSymptomsListBox.Value;
            app.selected(strcmp(app.selected,value)) = [];
            app.SelectedSymptomsListBox.Items = app.selected;
            app.SelectedSymptomsListBox.Value = {};

            % add user deletion back to symptoms
            app.symptoms(end+1) = {value};

            % add user deletion back to currentSymptoms if value matches search
            if contains(value,app.search)
                currentSymptoms = app.SymptomsListBox.Items;
                currentSymptoms(end+1) = {value};
                app.SymptomsListBox.Items = currentSymptoms;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SymptomsListBoxLabel = uilabel(app.UIFigure);
            app.SymptomsListBoxLabel.Text = 'Symptoms';
            app.SymptomsListBox = uilistbox(app.UIFigure);
            app.SymptomsListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10', 'Item 11', 'Item 12', 'Item 13', 'Item 14', 'Item 15', 'Item 16', 'Item 17', 'Item 18', 'Item 19', 'Item 20', 'Item 21', 'Item 22'};
            app.SymptomsListBox.ValueChangedFcn = createCallbackFcn(app, @SymptomsListBoxValueChanged, true);
            app.SymptomsListBox.Tooltip = {'Click on item to move it over to ''Selected Symptoms''.'};
            app.SymptomsListBox.Value = {};
            app.SelectedSymptomsListBoxLabel = uilabel(app.UIFigure);
            app.SelectedSymptomsListBoxLabel.Text = 'Selected Symptoms';
            app.SelectedSymptomsListBox = uilistbox(app.UIFigure);
            app.SelectedSymptomsListBox.Items = {};
            app.SelectedSymptomsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedSymptomsListBoxValueChanged, true);
            app.SelectedSymptomsListBox.Tooltip = {'Click on item to move it over to ''Symptoms''.'};
            app.SelectedSymptomsListBox.Value = {};
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Tooltip = {'For a successful prediction, pick a minimum of 3 symptoms.'};
            app.PredictButton.Text = 'Predict';
            app.SearchField = uieditfield(app.UIFigure, 'text');
            app.SearchField.ValueChangedFcn = createCallbackFcn(app, @SearchFieldValueChanged, true);
            app.SearchField.Tooltip = {'Search for a symptom.'; '(Words have to be at least 3 letters!)'};
            app.SearchField.Placeholder = 'Search...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeneralApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end