classdef HeartApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PredictButton                   matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        RestingElectrocardiographicResultsDropDown  matlab.ui.control.DropDown
        RestingElectrocardiographicResultsLabel  matlab.ui.control.Label
        STSlopeDropDown                 matlab.ui.control.DropDown
        STSlopeDropDownLabel            matlab.ui.control.Label
        ChestPainTypeDropDown           matlab.ui.control.DropDown
        ChestPainTypeDropDownLabel      matlab.ui.control.Label
        FluoroscopySpinner              matlab.ui.control.Spinner
        FluoroscopySpinnerLabel         matlab.ui.control.Label
        ThalassemiaDropDown             matlab.ui.control.DropDown
        ThalassemiaDropDownLabel        matlab.ui.control.Label
        STDepressionEditField           matlab.ui.control.NumericEditField
        STDepressionEditFieldLabel      matlab.ui.control.Label
        MaxHeartRateEditField           matlab.ui.control.NumericEditField
        MaxHeartRateEditFieldLabel      matlab.ui.control.Label
        SerumCholesterolEditField       matlab.ui.control.NumericEditField
        SerumCholesterolEditFieldLabel  matlab.ui.control.Label
        RestingBloodPressureEditField   matlab.ui.control.NumericEditField
        RestingBloodPressureEditFieldLabel  matlab.ui.control.Label
        ExerciseinducedAnginaCheckBox   matlab.ui.control.CheckBox
        HighFastingBloodSugarCheckBox   matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        HistoryofDiabetesDropDown       matlab.ui.control.DropDown
        HistoryofDiabetesDropDownLabel  matlab.ui.control.Label
        GeneralHealthDropDown           matlab.ui.control.DropDown
        GeneralHealthDropDownLabel      matlab.ui.control.Label
        DaysofbadPhysicalHealthSpinner  matlab.ui.control.Spinner
        DaysofbadPhysicalHealthSpinnerLabel  matlab.ui.control.Label
        DaysofbadMentalHealthSpinner    matlab.ui.control.Spinner
        DaysofbadMentalHealthSpinnerLabel  matlab.ui.control.Label
        HeavyDrinkingCheckBox           matlab.ui.control.CheckBox
        SmokerCheckBox                  matlab.ui.control.CheckBox
        DifficultyWalkingorClimbingStairsCheckBox  matlab.ui.control.CheckBox
        PhysicalActivityduringPastMonthCheckBox  matlab.ui.control.CheckBox
        HistoryofStrokeCheckBox         matlab.ui.control.CheckBox
        HistoryofHighBloodPressureCheckBox  matlab.ui.control.CheckBox
        HistoryofHighBloodCholesterolCheckBox  matlab.ui.control.CheckBox
        BMIEditField                    matlab.ui.control.NumericEditField
        BMIEditFieldLabel               matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            % === Parsing for heart disease risk ===
            HighBP = [app.HistoryofHighBloodPressureCheckBox.Value];
            HighChol = [app.HistoryofHighBloodCholesterolCheckBox.Value];
            BMI = [app.BMIEditField.Value];
            Smoker = [app.SmokerCheckBox.Value];
            Stroke = [app.HistoryofStrokeCheckBox.Value];
            Diabetes = [app.HistoryofDiabetesDropDown.Value];
            PhysActivity = [app.PhysicalActivityduringPastMonthCheckBox.Value];
            HvyAlcoholConsump = [app.HeavyDrinkingCheckBox.Value];
            GenHlth =  [app.GeneralHealthDropDown.Value];
            MentHlth = [app.DaysofbadMentalHealthSpinner.Value];
            PhysHlth = [app.DaysofbadPhysicalHealthSpinner.Value];
            DiffWalk = [app.DifficultyWalkingorClimbingStairsCheckBox.Value];
            switch app.SexDropDown.Value
                case "Male"
                    Sex = [0];
                case "Female"
                    Sex = [1];
            end
            switch true
                case app.AgeEditField.Value>=18 && app.AgeEditField.Value<=24
                    Age = [1];
                case app.AgeEditField.Value>=25 && app.AgeEditField.Value<=29
                    Age = [2];
                case app.AgeEditField.Value>=30 && app.AgeEditField.Value<=34
                    Age = [3];
                case app.AgeEditField.Value>=35 && app.AgeEditField.Value<=39
                    Age = [4];
                case app.AgeEditField.Value>=40 && app.AgeEditField.Value<=44
                    Age = [5];
                case app.AgeEditField.Value>=45 && app.AgeEditField.Value<=49
                    Age = [6];
                case app.AgeEditField.Value>=50 && app.AgeEditField.Value<=54
                    Age = [7];
                case app.AgeEditField.Value>=55 && app.AgeEditField.Value<=59
                    Age = [8];
                case app.AgeEditField.Value>=60 && app.AgeEditField.Value<=64
                    Age = [9];
                case app.AgeEditField.Value>=65 && app.AgeEditField.Value<=69
                    Age = [10];
                case app.AgeEditField.Value>=70 && app.AgeEditField.Value<=74
                    Age = [11];
                case app.AgeEditField.Value>=75 && app.AgeEditField.Value<=79
                    Age = [12];
                case app.AgeEditField.Value>=80
                    Age = [13];
                otherwise
                    Age = [14];
            end

            % Table for riskFactors
            indicators = table(HighBP, HighChol, BMI, Smoker, Stroke, Diabetes, ...
                PhysActivity, HvyAlcoholConsump, GenHlth, MentHlth, PhysHlth, DiffWalk, Sex, Age);

            % === Parsing for heart disease diagnosis ===
            age = [app.AgeEditField.Value];
            switch app.SexDropDown.Value
                case "Male"
                    sex = [0];
                case "Female"
                    sex = [1];
            end
            cp = [app.ChestPainTypeDropDown.Value];
            trestbps = [app.RestingBloodPressureEditField.Value];
            chol = [app.SerumCholesterolEditField.Value];
            fbs = [app.HighFastingBloodSugarCheckBox.Value];
            restecg = [str2double(app.RestingElectrocardiographicResultsDropDown.Value)];
            thalach = [app.MaxHeartRateEditField.Value];
            exang = [app.ExerciseinducedAnginaCheckBox.Value];
            oldpeak = [app.STDepressionEditField.Value];
            slope = [str2double(app.STSlopeDropDown.Value)];
            ca = [app.FluoroscopySpinner.Value];
            thal = [app.ThalassemiaDropDown.Value];

            % Table for diagnosis
            levels = table(age, sex, cp, trestbps, chol, fbs, restecg, thalach, exang, oldpeak, slope, ca, thal);

            % === Run predictions and send output to PredictionsApp ===
            diagnosis = HeartDiseaseCommunicator.returnPrediction(levels, indicators);
            info = HeartDiseaseCommunicator.returnInfo(levels, indicators);
            [unhealthy, categories] = HeartDiseaseCommunicator.getUnhealthyValues(levels, indicators);
            inputStr1 = SuperCommunicator.inputToStringArray(levels);
            inputStr2 = SuperCommunicator.inputToStringArray(indicators);
            unhealthyCategories = SuperCommunicator.printUnhealthyCategories([levels indicators], categories);
            myValues = [inputStr1 inputStr2 newline "Your unhealthy values:" unhealthyCategories];
            PredictionsApp(diagnosis, info,myValues, 'Heart');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.AgeEditFieldLabel = uilabel(app.Panel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.Panel, 'numeric');
            app.AgeEditField.Limits = [0 Inf];
            app.AgeEditField.ValueDisplayFormat = '%.0f';
            app.SexDropDownLabel = uilabel(app.Panel);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.Panel);
            app.SexDropDown.Items = {'Male', 'Female'};
            app.SexDropDown.Value = 'Male';
            app.Panel_2 = uipanel(app.UIFigure);
            app.BMIEditFieldLabel = uilabel(app.Panel_2);
            app.BMIEditFieldLabel.Tooltip = {'The Body Mass Index is calculated by dividing weight (kg) by height'; 'squared (m^2).'};
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.Panel_2, 'numeric');
            app.BMIEditField.Limits = [0 Inf];
            app.BMIEditField.Tooltip = {''};
            app.HistoryofHighBloodCholesterolCheckBox = uicheckbox(app.Panel_2);
            app.HistoryofHighBloodCholesterolCheckBox.Tooltip = {'Higher than 240 mg/dl total cholesterol.'};
            app.HistoryofHighBloodCholesterolCheckBox.Text = 'History of High Blood Cholesterol';
            app.HistoryofHighBloodPressureCheckBox = uicheckbox(app.Panel_2);
            app.HistoryofHighBloodPressureCheckBox.Tooltip = {'Higher than 120 (top number) or 80 (bottom number).'};
            app.HistoryofHighBloodPressureCheckBox.Text = 'History of High Blood Pressure';
            app.HistoryofStrokeCheckBox = uicheckbox(app.Panel_2);
            app.HistoryofStrokeCheckBox.Tooltip = {'Have you ever (been told you) had a stroke?'};
            app.HistoryofStrokeCheckBox.Text = 'History of Stroke';
            app.PhysicalActivityduringPastMonthCheckBox = uicheckbox(app.Panel_2);
            app.PhysicalActivityduringPastMonthCheckBox.Tooltip = {'Have you done any physical activity or exercise during the past 30 days other than your regular job?'};
            app.PhysicalActivityduringPastMonthCheckBox.Text = 'Physical Activity during Past Month';
            app.DifficultyWalkingorClimbingStairsCheckBox = uicheckbox(app.Panel_2);
            app.DifficultyWalkingorClimbingStairsCheckBox.Tooltip = {'Do you have serious difficulty walking or climbing stairs?'};
            app.DifficultyWalkingorClimbingStairsCheckBox.Text = 'Difficulty Walking or Climbing Stairs';
            app.SmokerCheckBox = uicheckbox(app.Panel_2);
            app.SmokerCheckBox.Tooltip = {'Have you smoked at least 100 cigarettes (= 5 packs) in your entire life?'};
            app.SmokerCheckBox.Text = 'Smoker';
            app.HeavyDrinkingCheckBox = uicheckbox(app.Panel_2);
            app.HeavyDrinkingCheckBox.Tooltip = {'Do you have more than 14 (for men) / 7 (for women) drinks per week?'};
            app.HeavyDrinkingCheckBox.Text = 'Heavy Drinking';
            app.DaysofbadMentalHealthSpinnerLabel = uilabel(app.Panel_2);
            app.DaysofbadMentalHealthSpinnerLabel.Tooltip = {'Mental health includes stress, depression and problems with emotions.'};
            app.DaysofbadMentalHealthSpinnerLabel.Text = 'Days of bad Mental Health';
            app.DaysofbadMentalHealthSpinner = uispinner(app.Panel_2);
            app.DaysofbadMentalHealthSpinner.Tooltip = {''};
            app.DaysofbadPhysicalHealthSpinnerLabel = uilabel(app.Panel_2);
            app.DaysofbadPhysicalHealthSpinnerLabel.Tooltip = {'Physical health includes physical illness and injury.'};
            app.DaysofbadPhysicalHealthSpinnerLabel.Text = 'Days of bad Physical Health';
            app.DaysofbadPhysicalHealthSpinner = uispinner(app.Panel_2);
            app.GeneralHealthDropDownLabel = uilabel(app.Panel_2);
            app.GeneralHealthDropDownLabel.Text = 'General Health';
            app.GeneralHealthDropDown = uidropdown(app.Panel_2);
            app.GeneralHealthDropDown.Items = {'Excellent', 'Very Good', 'Good', 'Fair', 'Poor'};
            app.GeneralHealthDropDown.ItemsData = [1 2 3 4 5];
            app.GeneralHealthDropDown.Value = 3;
            app.HistoryofDiabetesDropDownLabel = uilabel(app.Panel_2);
            app.HistoryofDiabetesDropDownLabel.Text = 'History of Diabetes';
            app.HistoryofDiabetesDropDown = uidropdown(app.Panel_2);
            app.HistoryofDiabetesDropDown.Items = {'No', 'Yes, during pregnancy', 'Yes'};
            app.HistoryofDiabetesDropDown.ItemsData = [0 1 2];
            app.HistoryofDiabetesDropDown.Value = 0;
            app.Panel_3 = uipanel(app.UIFigure);
            app.HighFastingBloodSugarCheckBox = uicheckbox(app.Panel_3);
            app.HighFastingBloodSugarCheckBox.Tooltip = {'Fasting Blood Sugar is above 120 mg/dl.'};
            app.HighFastingBloodSugarCheckBox.Text = 'High Fasting Blood Sugar';
            app.ExerciseinducedAnginaCheckBox = uicheckbox(app.Panel_3);
            app.ExerciseinducedAnginaCheckBox.Tooltip = {'Does exercise cause angina?'};
            app.ExerciseinducedAnginaCheckBox.Text = 'Exercise induced Angina';
            app.RestingBloodPressureEditFieldLabel = uilabel(app.Panel_3);
            app.RestingBloodPressureEditFieldLabel.Tooltip = {'Top number of resting blood pressure.'};
            app.RestingBloodPressureEditFieldLabel.Text = 'Resting Blood Pressure';
            app.RestingBloodPressureEditField = uieditfield(app.Panel_3, 'numeric');
            app.SerumCholesterolEditFieldLabel = uilabel(app.Panel_3);
            app.SerumCholesterolEditFieldLabel.Tooltip = {'Serum cholesterol in mg/dl.'};
            app.SerumCholesterolEditFieldLabel.Text = 'Serum Cholesterol ';
            app.SerumCholesterolEditField = uieditfield(app.Panel_3, 'numeric');
            app.MaxHeartRateEditFieldLabel = uilabel(app.Panel_3);
            app.MaxHeartRateEditFieldLabel.Tooltip = {'Maximum heart rate achieved by exercising.'};
            app.MaxHeartRateEditFieldLabel.Text = 'Max Heart Rate';
            app.MaxHeartRateEditField = uieditfield(app.Panel_3, 'numeric');
            app.STDepressionEditFieldLabel = uilabel(app.Panel_3);
            app.STDepressionEditFieldLabel.Tooltip = {'ST depression induced by exercise.'};
            app.STDepressionEditFieldLabel.Text = 'ST Depression';
            app.STDepressionEditField = uieditfield(app.Panel_3, 'numeric');
            app.ThalassemiaDropDownLabel = uilabel(app.Panel_3);
            app.ThalassemiaDropDownLabel.Tooltip = {'normal: normal blood flow'; 'fixed defect (ischemia): no blood flow in some parts of the heart'; 'reversible defect: blood flow observable but not normal'};
            app.ThalassemiaDropDownLabel.Text = 'Thalassemia';
            app.ThalassemiaDropDown = uidropdown(app.Panel_3);
            app.ThalassemiaDropDown.Items = {'Fixed Defect', 'Normal', 'Reversible Defect'};
            app.ThalassemiaDropDown.ItemsData = [1 2 3];
            app.ThalassemiaDropDown.Value = 2;
            app.FluoroscopySpinnerLabel = uilabel(app.Panel_3);
            app.FluoroscopySpinnerLabel.Tooltip = {'Number of major blood vessels coloured by fluoroscopy.'};
            app.FluoroscopySpinnerLabel.Text = 'Fluoroscopy';
            app.FluoroscopySpinner = uispinner(app.Panel_3);
            app.FluoroscopySpinner.Limits = [0 3];
            app.FluoroscopySpinner.ValueDisplayFormat = '%.0f';
            app.ChestPainTypeDropDownLabel = uilabel(app.Panel_3);
            app.ChestPainTypeDropDownLabel.Tooltip = {'asymptomatic: no angina/pain'; 'atypical angina: chest discomfort (like indigestion feeling)'; 'non-anginal: pain not caused by angina'; 'typical angina: tightness/heaviness in chest'};
            app.ChestPainTypeDropDownLabel.Text = 'Chest Pain Type';
            app.ChestPainTypeDropDown = uidropdown(app.Panel_3);
            app.ChestPainTypeDropDown.Items = {'Non-Anginal', 'Typical Angina', 'Atypical Angina', 'Asymptomatic'};
            app.ChestPainTypeDropDown.ItemsData = [2 3 1 0];
            app.ChestPainTypeDropDown.Value = 2;
            app.STSlopeDropDownLabel = uilabel(app.Panel_3);
            app.STSlopeDropDownLabel.Tooltip = {'Slope of peak exercise.'};
            app.STSlopeDropDownLabel.Text = 'ST Slope';
            app.STSlopeDropDown = uidropdown(app.Panel_3);
            app.STSlopeDropDown.Items = {'Downsloping', 'Flat', 'Upsloping'};
            app.STSlopeDropDown.ItemsData = {'0', '1', '2'};
            app.STSlopeDropDown.Value = '1';
            app.RestingElectrocardiographicResultsLabel = uilabel(app.Panel_3);
            app.RestingElectrocardiographicResultsLabel.Tooltip = {'lv hypertrophy: showing probable or definite left ventricular hypertrophy'; 'normal'; 'stt abnormality: having ST-T wave abnormality'};
            app.RestingElectrocardiographicResultsLabel.Text = 'Resting Electrocardiographic Results:';
            app.RestingElectrocardiographicResultsDropDown = uidropdown(app.Panel_3);
            app.RestingElectrocardiographicResultsDropDown.Items = {'lv Hypertrophy', 'Normal', 'stt Abnormality'};
            app.RestingElectrocardiographicResultsDropDown.ItemsData = {'0', '1', '2'};
            app.RestingElectrocardiographicResultsDropDown.Value = '1';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HeartApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end