classdef SkinApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PickCoordinatesButton          matlab.ui.control.Button
        VaporPressureEditField         matlab.ui.control.NumericEditField
        VaporPressureEditFieldLabel    matlab.ui.control.Label
        CloudcoverEditField            matlab.ui.control.NumericEditField
        CloudcoverEditFieldLabel       matlab.ui.control.Label
        DominantlandcoverSpinner       matlab.ui.control.Spinner
        DominantlandcoverSpinnerLabel  matlab.ui.control.Label
        FrostydayfrequencyEditField    matlab.ui.control.NumericEditField
        FrostydayfrequencyEditFieldLabel  matlab.ui.control.Label
        WetdayfrequencyEditField       matlab.ui.control.NumericEditField
        WetdayfrequencyEditFieldLabel  matlab.ui.control.Label
        PrecipitationEditField         matlab.ui.control.NumericEditField
        PrecipitationEditFieldLabel    matlab.ui.control.Label
        LongitudeGauge                 matlab.ui.control.SemicircularGauge
        LongitudeGaugeLabel            matlab.ui.control.Label
        LatitudeGauge                  matlab.ui.control.SemicircularGauge
        LatitudeGaugeLabel             matlab.ui.control.Label
        MeanMaxSlider                  matlab.ui.control.Slider
        MeanMaxSliderLabel             matlab.ui.control.Label
        MeanSlider                     matlab.ui.control.Slider
        MeanSliderLabel                matlab.ui.control.Label
        MeanMinSlider                  matlab.ui.control.Slider
        MeanMinSliderLabel             matlab.ui.control.Label
        LongitudeSpinner               matlab.ui.control.Spinner
        LatitudeSpinner                matlab.ui.control.Spinner
        DailyTemperatureCLabel         matlab.ui.control.Label
        PredictButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            %Parameters fro lumpy skin
            x = [app.LongitudeSpinner.Value];
            y = [app.LatitudeSpinner.Value];

            cld = [app.CloudcoverEditField.Value];
            frs = [app.FrostydayfrequencyEditField.Value];
            pre = [app.PrecipitationEditField.Value];

            tmn = [app.MeanMinSlider.Value];
            tmp = [app.MeanSlider.Value];
            tmx = [app.MeanMaxSlider.Value];

            vap = [app.VaporPressureEditField.Value];
            wet = [app.WetdayfrequencyEditField.Value];
            
            dominant_land_cover = [app.DominantlandcoverSpinner.Value];

            %Table for diagnosis
            input = table(cld, dominant_land_cover, frs, pre, tmn, tmp, tmx, vap, wet,  x, y);

            %Resuls
            diagnosis = LumpySkinCommunicator.returnPrediction(input);
            info = LumpySkinCommunicator.returnInfo(input);
            [unhealthy, categories] = LumpySkinCommunicator.getUnhealthyValues(input);
            inputStr = SuperCommunicator.inputToStringArray(input);
            categories = SuperCommunicator.printUnhealthyCategories(input, categories);
            myValues = [inputStr newline "Your unhealthy values:" categories];
            PredictionsApp(diagnosis,info,myValues, 'Lumpy');
        end

        % Value changed function: LatitudeSpinner
        function LatitudeSpinnerValueChanged(app, event)
            app.LatitudeGauge.Value = app.LatitudeSpinner.Value;
            
        end

        % Value changed function: LongitudeSpinner
        function LongitudeSpinnerValueChanged(app, event)
            app.LongitudeGauge.Value = app.LongitudeSpinner.Value;
            
        end

        % Button pushed function: PickCoordinatesButton
        function PickCoordinatesButtonPushed(app, event)
            [lat, lon]= map();
            app.LatitudeGauge.Value = lat;
            app.LongitudeGauge.Value = lon;
            app.LongitudeSpinner.Value = lon;
            app.LatitudeSpinner.Value = lat;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.DailyTemperatureCLabel = uilabel(app.UIFigure);
            app.DailyTemperatureCLabel.Text = 'Daily Temperature C°';
            app.LatitudeSpinner = uispinner(app.UIFigure);
            app.LatitudeSpinner.Limits = [-90 90];
            app.LatitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @LatitudeSpinnerValueChanged, true);
            app.LongitudeSpinner = uispinner(app.UIFigure);
            app.LongitudeSpinner.Limits = [0 180];
            app.LongitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @LongitudeSpinnerValueChanged, true);
            app.MeanMinSliderLabel = uilabel(app.UIFigure);
            app.MeanMinSliderLabel.Tooltip = {'Average daily minimun temperature.'};
            app.MeanMinSliderLabel.Text = 'Mean Min ';
            app.MeanMinSlider = uislider(app.UIFigure);
            app.MeanMinSlider.Limits = [-40 60];
            app.MeanSliderLabel = uilabel(app.UIFigure);
            app.MeanSliderLabel.Tooltip = {'Average daily temperature.'};
            app.MeanSliderLabel.Text = 'Mean ';
            app.MeanSlider = uislider(app.UIFigure);
            app.MeanSlider.Limits = [-40 60];
            app.MeanMaxSliderLabel = uilabel(app.UIFigure);
            app.MeanMaxSliderLabel.Tooltip = {'Average daily maximun temperature.'};
            app.MeanMaxSliderLabel.Text = 'Mean Max ';
            app.MeanMaxSlider = uislider(app.UIFigure);
            app.MeanMaxSlider.Limits = [-40 60];
            app.LatitudeGaugeLabel = uilabel(app.UIFigure);
            app.LatitudeGaugeLabel.Text = 'Latitude';
            app.LatitudeGauge = uigauge(app.UIFigure, 'semicircular');
            app.LatitudeGauge.Limits = [-90 90];
            app.LongitudeGaugeLabel = uilabel(app.UIFigure);
            app.LongitudeGaugeLabel.Text = 'Longitude';
            app.LongitudeGauge = uigauge(app.UIFigure, 'semicircular');
            app.LongitudeGauge.Limits = [0 180];
            app.LongitudeGauge.ScaleDirection = 'counterclockwise';
            app.PrecipitationEditFieldLabel = uilabel(app.UIFigure);
            app.PrecipitationEditFieldLabel.Tooltip = {'Average quantity of precipitation in mm per month.'};
            app.PrecipitationEditFieldLabel.Text = 'Precipitation';
            app.PrecipitationEditField = uieditfield(app.UIFigure, 'numeric');
            app.WetdayfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.WetdayfrequencyEditFieldLabel.Tooltip = {'Average number of days per month with presence of rain.'};
            app.WetdayfrequencyEditFieldLabel.Text = 'Wet day frequency';
            app.WetdayfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.WetdayfrequencyEditField.Limits = [0 31];
            app.FrostydayfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrostydayfrequencyEditFieldLabel.Tooltip = {'Average number of days per month with presence of frost.'};
            app.FrostydayfrequencyEditFieldLabel.Text = 'Frosty day frequency';
            app.FrostydayfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrostydayfrequencyEditField.Limits = [0 31];
            app.DominantlandcoverSpinnerLabel = uilabel(app.UIFigure);
            app.DominantlandcoverSpinnerLabel.Tooltip = {'Category of most dominant land cover'};
            app.DominantlandcoverSpinnerLabel.Text = 'Dominant land cover';
            app.DominantlandcoverSpinner = uispinner(app.UIFigure);
            app.DominantlandcoverSpinner.Limits = [0 11];
            app.CloudcoverEditFieldLabel = uilabel(app.UIFigure);
            app.CloudcoverEditFieldLabel.Tooltip = {'Fraction of the sky obscured by clouds'};
            app.CloudcoverEditFieldLabel.Text = 'Cloud cover';
            app.CloudcoverEditField = uieditfield(app.UIFigure, 'numeric');
            app.VaporPressureEditFieldLabel = uilabel(app.UIFigure);
            app.VaporPressureEditFieldLabel.Tooltip = {'Mean vapor pressure in milibars.'};
            app.VaporPressureEditFieldLabel.Text = 'Vapor Pressure';
            app.VaporPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.PickCoordinatesButton = uibutton(app.UIFigure, 'push');
            app.PickCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @PickCoordinatesButtonPushed, true);
            app.PickCoordinatesButton.Tooltip = {'Open map to pick a coordinate point'};
            app.PickCoordinatesButton.Text = 'Pick Coordinates';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SkinApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end