classdef GeneralApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EditField                     matlab.ui.control.EditField
        PredictButton                 matlab.ui.control.Button
        SelectedSymptomsListBox       matlab.ui.control.ListBox
        SelectedSymptomsListBoxLabel  matlab.ui.control.Label
        SymptomsListBox               matlab.ui.control.ListBox
        SymptomsListBoxLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        symptoms %symptoms from dataset
        selected %selected symptoms
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.symptoms = readtable(['Project' filesep 'ML' filesep 'General' filesep 'Symptom-severity.csv']).Symptom; % mySymptoms is a table with variables "Symptom" and "weight"
            app.SymptomsListBox.Items = app.symptoms; % display array extracted from "Symptom" variable
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            diagnosis = GeneralCommunicator.returnPrediction(app.selected);
            PredictionsApp(diagnosis);            
        end

        % Value changed function: SymptomsListBox
        function SymptomsListBoxValueChanged(app, event)
            app.selected = app.SymptomsListBox.Value;
            app.SelectedSymptomsListBox.Items = app.selected;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            search = split(app.EditField.Value);
            found = app.symptoms(contains(app.symptoms,search));
            app.SymptomsListBox.Items = found;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SymptomsListBoxLabel = uilabel(app.UIFigure);
            app.SymptomsListBoxLabel.Text = 'Symptoms';
            app.SymptomsListBox = uilistbox(app.UIFigure);
            app.SymptomsListBox.Items = {''};
            app.SymptomsListBox.Multiselect = 'on';
            app.SymptomsListBox.ValueChangedFcn = createCallbackFcn(app, @SymptomsListBoxValueChanged, true);
            app.SymptomsListBox.Tooltip = {'Hold "Ctrl" to multiselect.'};
            app.SymptomsListBox.Value = {};
            app.SelectedSymptomsListBoxLabel = uilabel(app.UIFigure);
            app.SelectedSymptomsListBoxLabel.Text = 'Selected Symptoms';
            app.SelectedSymptomsListBox = uilistbox(app.UIFigure);
            app.SelectedSymptomsListBox.Items = {};
            app.SelectedSymptomsListBox.Tooltip = {''};
            app.SelectedSymptomsListBox.Value = {};
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {'Search for a symptom.'};
            app.EditField.Placeholder = 'Search...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeneralApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end