classdef HeartApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PredictButton                   matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        MajorVesselsColoredbyFluoroscopySpinner  matlab.ui.control.Spinner
        MajorVesselsColoredbyFluoroscopySpinnerLabel  matlab.ui.control.Label
        RestingElectrocardiographicResultsSpinner  matlab.ui.control.Spinner
        RestingElectrocardiographicResultsSpinnerLabel  matlab.ui.control.Label
        ThalassemiaDropDown             matlab.ui.control.DropDown
        ThalassemiaDropDownLabel        matlab.ui.control.Label
        STSlopeEditField                matlab.ui.control.NumericEditField
        STSlopeEditFieldLabel           matlab.ui.control.Label
        STDepressionEditField           matlab.ui.control.NumericEditField
        STDepressionEditFieldLabel      matlab.ui.control.Label
        MaxHeartRateachievedEditField   matlab.ui.control.NumericEditField
        MaxHeartRateachievedEditFieldLabel  matlab.ui.control.Label
        SerumCholesterolmgdlEditField   matlab.ui.control.NumericEditField
        SerumCholesterolmgdlEditFieldLabel  matlab.ui.control.Label
        RestingBloodPressureEditField   matlab.ui.control.NumericEditField
        RestingBloodPressureEditFieldLabel  matlab.ui.control.Label
        ExerciseinducedAnginaCheckBox   matlab.ui.control.CheckBox
        FastingBloodSugar120mgdlCheckBox  matlab.ui.control.CheckBox
        ChestPainSpinner                matlab.ui.control.Spinner
        ChestPainSpinnerLabel           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        HistoryofDiabetesDropDown       matlab.ui.control.DropDown
        HistoryofDiabetesDropDownLabel  matlab.ui.control.Label
        GeneralHealthDropDown           matlab.ui.control.DropDown
        GeneralHealthDropDownLabel      matlab.ui.control.Label
        DaysofgoodPhysicalHealthSpinner  matlab.ui.control.Spinner
        DaysofgoodPhysicalHealthSpinnerLabel  matlab.ui.control.Label
        DaysofgoodMentalHealthSpinner   matlab.ui.control.Spinner
        DaysofgoodMentalHealthSpinnerLabel  matlab.ui.control.Label
        HeavyDrinking147DrinksaWeekCheckBox  matlab.ui.control.CheckBox
        Smokedmorethan100CigarettesCheckBox  matlab.ui.control.CheckBox
        DifficultyWalkingorClimbingStairsCheckBox  matlab.ui.control.CheckBox
        PhysicalActivityduringPastMonthCheckBox  matlab.ui.control.CheckBox
        HistoryofStrokeCheckBox         matlab.ui.control.CheckBox
        HistoryofHighBloodPressureCheckBox  matlab.ui.control.CheckBox
        HistoryofHighBloodCholesterolCheckBox  matlab.ui.control.CheckBox
        BMIEditField                    matlab.ui.control.NumericEditField
        BMIEditFieldLabel               matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            %Parsing for heart disease risk
            HighBP = [app.HistoryofHighBloodPressureCheckBox.Value];
            HighChol = [app.HistoryofHighBloodCholesterolCheckBox.Value];
            BMI = [app.BMIEditField.Value];
            Smoker = [app.Smokedmorethan100CigarettesCheckBox.Value];
            Stroke = [app.HistoryofStrokeCheckBox.Value];
            %Diabetes = [app.HistoryofdiabetesDropDown.Value];
            switch app.HistoryofDiabetesDropDown.Value 
                case "No"
                    Diabetes = [0];
                case "Yes, during pregnancy"
                    Diabetes = [1];
                case "Yes"
                    Diabetes = [2];
                otherwise
                    Diabetes = [0];
            end
            PhysActivity = [app.PhysicalActivityduringPastMonthCheckBox.Value];
            HvyAlcoholConsump = [app.HeavyDrinking147DrinksaWeekCheckBox.Value];
            %GenHlth = [app.GeneralhealthDropDown.Value];
            switch app.GeneralHealthDropDown.Value
                case "Excellent"
                    GenHlth = [1];
                case "Very good"
                    GenHlth = [2];
                case "Good"
                    GenHlth = [3];
                case "Fair"
                    GenHlth = [4];
                case "Poor"
                    GenHlth = [5];
                otherwise
                    GenHlth = [3];
            end
            MentHlth = [app.DaysofgoodMentalHealthSpinner.Value];
            PhysHlth = [app.DaysofgoodPhysicalHealthSpinner.Value];
            DiffWalk = [app.DifficultyWalkingorClimbingStairsCheckBox.Value];
            %Sex = [app.SexDropDown_2.Value];
            switch app.SexDropDown.Value
                case "Male"
                    Sex = [0];
                case "Female"
                    Sex = [1];
                otherwise
                    Sex = [0];
            end
            %Age = [app.AgeDropDown.Value];
            switch true
                case app.AgeEditField.Value>=18 && app.AgeEditField.Value<=24
                    Age = [1];
                case app.AgeEditField.Value>=25 && app.AgeEditField.Value<=29
                    Age = [2];
                case app.AgeEditField.Value>=30 && app.AgeEditField.Value<=34
                    Age = [3];
                case app.AgeEditField.Value>=35 && app.AgeEditField.Value<=39
                    Age = [4];
                case app.AgeEditField.Value>=40 && app.AgeEditField.Value<=44
                    Age = [5];
                case app.AgeEditField.Value>=45 && app.AgeEditField.Value<=49
                    Age = [6];
                case app.AgeEditField.Value>=50 && app.AgeEditField.Value<=54
                    Age = [7];
                case app.AgeEditField.Value>=55 && app.AgeEditField.Value<=59
                    Age = [8];
                case app.AgeEditField.Value>=60 && app.AgeEditField.Value<=64
                    Age = [9];
                case app.AgeEditField.Value>=65 && app.AgeEditField.Value<=69
                    Age = [10];
                case app.AgeEditField.Value>=70 && app.AgeEditField.Value<=74
                    Age = [11];
                case app.AgeEditField.Value>=75 && app.AgeEditField.Value<=79
                    Age = [12];
                case app.AgeEditField.Value>=80
                    Age = [13];
                otherwise
                    Age = [14];
            end

            %Table for riskFactors
            indicators = table(HighBP, HighChol, BMI, Smoker, Stroke, Diabetes, ...
                PhysActivity, HvyAlcoholConsump, GenHlth, MentHlth, PhysHlth, DiffWalk, Sex, Age);

            %Second tab for heart disease diagnosis
            age = [app.AgeEditField.Value];
            %sex = [app.SexDropDown.Value];
            switch app.SexDropDown.Value
                case "Male"
                    sex = [0];
                case "Female"
                    sex = [1];
                otherwise
                    sex = [0];
            end
            cp = [app.ChestPainSpinner.Value];
            trestbps = [app.RestingBloodPressureEditField.Value];
            chol = [app.SerumCholesterolmgdlEditField.Value];
            %fbs = [app.Fastingbloodsugar120mgdlDropDown.Value];
            fbs = app.FastingBloodSugar120mgdlCheckBox.Value;
            restecg = [app.RestingElectrocardiographicResultsSpinner.Value];
            thalach = [app.MaxHeartRateachievedEditField.Value];
            %exang = [];
            exang = app.ExerciseinducedAnginaCheckBox.Value;
            oldpeak = [app.STDepressionEditField.Value];
            slope = [app.STSlopeEditField.Value];
            ca = [app.MajorVesselsColoredbyFluoroscopySpinner.Value];
            %thal = [app.ThalassemiaDropDown.Value];
            switch app.ThalassemiaDropDown.Value
                case "Normal"
                    thal = [0];
                case "Fixed defect"
                    thal = [1];
                case "Reversible defect"
                    thal = [2];
                otherwise
                    thal = [0];
            end
            %Table for diagnosis
            levels = table(age, sex, cp, trestbps, chol, fbs, restecg, thalach, exang, oldpeak, slope, ca, thal);
            
            %Array with both tables of values [riskFactors,diagnosis];
            %input = {indicators,levels};

            %%Testing
            %table1 = input(1,1);
            %element1 = table1{1,1} ;
            %app.TestEditField.Value = element1{1,"Age"} ;

            %%Result
            %app.TestEditField.Value = [indicators,diagnosis];
            diagnosis = HeartDiseaseCommunicator.returnPrediction(levels, indicators);
            PredictionsApp(diagnosis);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.AgeEditFieldLabel = uilabel(app.Panel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.Panel, 'numeric');
            app.AgeEditField.Limits = [0 Inf];
            app.AgeEditField.ValueDisplayFormat = '%.0f';
            app.SexDropDownLabel = uilabel(app.Panel);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.Panel);
            app.SexDropDown.Items = {'Male', 'Female'};
            app.SexDropDown.Value = 'Male';
            app.Panel_2 = uipanel(app.UIFigure);
            app.BMIEditFieldLabel = uilabel(app.Panel_2);
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.Panel_2, 'numeric');
            app.BMIEditField.Limits = [0 Inf];
            app.HistoryofHighBloodCholesterolCheckBox = uicheckbox(app.Panel_2);
            app.HistoryofHighBloodCholesterolCheckBox.Text = 'History of High Blood Cholesterol';
            app.HistoryofHighBloodPressureCheckBox = uicheckbox(app.Panel_2);
            app.HistoryofHighBloodPressureCheckBox.Text = 'History of High Blood Pressure';
            app.HistoryofStrokeCheckBox = uicheckbox(app.Panel_2);
            app.HistoryofStrokeCheckBox.Text = 'History of Stroke';
            app.PhysicalActivityduringPastMonthCheckBox = uicheckbox(app.Panel_2);
            app.PhysicalActivityduringPastMonthCheckBox.Text = 'Physical Activity during Past Month';
            app.DifficultyWalkingorClimbingStairsCheckBox = uicheckbox(app.Panel_2);
            app.DifficultyWalkingorClimbingStairsCheckBox.Text = 'Difficulty Walking or Climbing Stairs';
            app.Smokedmorethan100CigarettesCheckBox = uicheckbox(app.Panel_2);
            app.Smokedmorethan100CigarettesCheckBox.Text = 'Smoked more than 100 Cigarettes';
            app.HeavyDrinking147DrinksaWeekCheckBox = uicheckbox(app.Panel_2);
            app.HeavyDrinking147DrinksaWeekCheckBox.Text = 'Heavy Drinking (14/7 Drinks a Week)';
            app.DaysofgoodMentalHealthSpinnerLabel = uilabel(app.Panel_2);
            app.DaysofgoodMentalHealthSpinnerLabel.Text = 'Days of good Mental Health';
            app.DaysofgoodMentalHealthSpinner = uispinner(app.Panel_2);
            app.DaysofgoodPhysicalHealthSpinnerLabel = uilabel(app.Panel_2);
            app.DaysofgoodPhysicalHealthSpinnerLabel.Text = 'Days of good Physical Health';
            app.DaysofgoodPhysicalHealthSpinner = uispinner(app.Panel_2);
            app.GeneralHealthDropDownLabel = uilabel(app.Panel_2);
            app.GeneralHealthDropDownLabel.Text = 'General Health';
            app.GeneralHealthDropDown = uidropdown(app.Panel_2);
            app.GeneralHealthDropDown.Items = {'None', 'Excellent', 'Very Good', 'Good', 'Fair', 'Poor'};
            app.GeneralHealthDropDown.Value = 'None';
            app.HistoryofDiabetesDropDownLabel = uilabel(app.Panel_2);
            app.HistoryofDiabetesDropDownLabel.Text = 'History of Diabetes';
            app.HistoryofDiabetesDropDown = uidropdown(app.Panel_2);
            app.HistoryofDiabetesDropDown.Items = {'No', 'Yes, during pregnancy', 'Yes'};
            app.HistoryofDiabetesDropDown.Value = 'No';
            app.Panel_3 = uipanel(app.UIFigure);
            app.ChestPainSpinnerLabel = uilabel(app.Panel_3);
            app.ChestPainSpinnerLabel.Text = 'Chest Pain';
            app.ChestPainSpinner = uispinner(app.Panel_3);
            app.ChestPainSpinner.Limits = [0 4];
            app.ChestPainSpinner.ValueDisplayFormat = '%.0f';
            app.FastingBloodSugar120mgdlCheckBox = uicheckbox(app.Panel_3);
            app.FastingBloodSugar120mgdlCheckBox.Text = 'Fasting Blood Sugar > 120 mg/dl';
            app.ExerciseinducedAnginaCheckBox = uicheckbox(app.Panel_3);
            app.ExerciseinducedAnginaCheckBox.Text = 'Exercise induced Angina';
            app.RestingBloodPressureEditFieldLabel = uilabel(app.Panel_3);
            app.RestingBloodPressureEditFieldLabel.Text = 'Resting Blood Pressure';
            app.RestingBloodPressureEditField = uieditfield(app.Panel_3, 'numeric');
            app.SerumCholesterolmgdlEditFieldLabel = uilabel(app.Panel_3);
            app.SerumCholesterolmgdlEditFieldLabel.Text = 'Serum Cholesterol (mg/dl)';
            app.SerumCholesterolmgdlEditField = uieditfield(app.Panel_3, 'numeric');
            app.MaxHeartRateachievedEditFieldLabel = uilabel(app.Panel_3);
            app.MaxHeartRateachievedEditFieldLabel.Text = 'Max Heart Rate achieved';
            app.MaxHeartRateachievedEditField = uieditfield(app.Panel_3, 'numeric');
            app.STDepressionEditFieldLabel = uilabel(app.Panel_3);
            app.STDepressionEditFieldLabel.Text = 'ST Depression';
            app.STDepressionEditField = uieditfield(app.Panel_3, 'numeric');
            app.STSlopeEditFieldLabel = uilabel(app.Panel_3);
            app.STSlopeEditFieldLabel.Text = 'ST Slope';
            app.STSlopeEditField = uieditfield(app.Panel_3, 'numeric');
            app.ThalassemiaDropDownLabel = uilabel(app.Panel_3);
            app.ThalassemiaDropDownLabel.Text = 'Thalassemia';
            app.ThalassemiaDropDown = uidropdown(app.Panel_3);
            app.ThalassemiaDropDown.Items = {'Normal', 'Fixed Defect', 'Reversible Defect'};
            app.ThalassemiaDropDown.Value = 'Normal';
            app.RestingElectrocardiographicResultsSpinnerLabel = uilabel(app.Panel_3);
            app.RestingElectrocardiographicResultsSpinnerLabel.Text = 'Resting Electrocardiographic Results';
            app.RestingElectrocardiographicResultsSpinner = uispinner(app.Panel_3);
            app.RestingElectrocardiographicResultsSpinner.Limits = [0 2];
            app.RestingElectrocardiographicResultsSpinner.ValueDisplayFormat = '%.0f';
            app.MajorVesselsColoredbyFluoroscopySpinnerLabel = uilabel(app.Panel_3);
            app.MajorVesselsColoredbyFluoroscopySpinnerLabel.Text = 'Major Vessels Colored by Fluoroscopy';
            app.MajorVesselsColoredbyFluoroscopySpinner = uispinner(app.Panel_3);
            app.MajorVesselsColoredbyFluoroscopySpinner.Limits = [0 4];
            app.MajorVesselsColoredbyFluoroscopySpinner.ValueDisplayFormat = '%.0f';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HeartApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end