classdef PredictionsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        YourInputTextArea         matlab.ui.control.TextArea
        YourInputTextAreaLabel    matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        InformationTextArea       matlab.ui.control.TextArea
        InformationTextAreaLabel  matlab.ui.control.Label
        DiseaseTextArea           matlab.ui.control.TextArea
        DiseaseTextAreaLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        diagnosis % given diagnosis: string
        information % given information: array of strings
        input % given input: array of strings
        nameGui % name of the gui, that called PredictionsApp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, diagnosis, information, input, name)
            % set properties and values of text areas
            app.diagnosis = diagnosis;
            app.information = information;
            app.input = input;
            app.nameGui = name;
            app.DiseaseTextArea.Value = app.diagnosis;
            app.InformationTextArea.Value = app.information;
            app.YourInputTextArea.Value = app.input;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            disp('saving file...')
            name = SuperCommunicator.saveDiagnosis(app.diagnosis, app.information, app.input, app.nameGui);
            disp(strcat('saved file: ', name));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiseaseTextAreaLabel = uilabel(app.UIFigure);
            app.DiseaseTextAreaLabel.Text = 'Disease';
            app.DiseaseTextArea = uitextarea(app.UIFigure);
            app.InformationTextAreaLabel = uilabel(app.UIFigure);
            app.InformationTextAreaLabel.Tooltip = {'Information about your unhealthy values.'};
            app.InformationTextAreaLabel.Text = 'Information ';
            app.InformationTextArea = uitextarea(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.YourInputTextAreaLabel = uilabel(app.UIFigure);
            app.YourInputTextAreaLabel.Text = 'Your Input';
            app.YourInputTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PredictionsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end