classdef StartUpApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LumpySkinButton         matlab.ui.control.Button
        HeartDiseaseButton      matlab.ui.control.Button
        DiabetesButton          matlab.ui.control.Button
        GeneralButton           matlab.ui.control.Button
        WhatdiagnosistoolLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % test: set current folder
            % set arbitary boundary, but higher the depth of folder to
            % prevent endless loop
            for i = 1:7
                currentFolder = pwd;
                [~, lastFolderName, ~] = fileparts(currentFolder);
                if isequal(lastFolderName, 'Project')
                    break;
                elseif isequal(lastFolderName, 'Teamprojekt-Diagnosis-System');
                    cd 'Project';
                    break;
                end
                cd ..;
            end
            addpath(genpath('Project'))
            addpath(genpath('ML'))
            addpath(genpath('GUI'))
            addpath(genpath('Downloads'))
            
        end

        % Button pushed function: GeneralButton
        function GeneralButtonPushed(app, event)
            GeneralApp;
        end

        % Button pushed function: DiabetesButton
        function DiabetesButtonPushed(app, event)
            DiabetesApp;
        end

        % Button pushed function: HeartDiseaseButton
        function HeartDiseaseButtonPushed(app, event)
            HeartApp;
        end

        % Button pushed function: LumpySkinButton
        function LumpySkinButtonPushed(app, event)
            SkinApp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WhatdiagnosistoolLabel = uilabel(app.UIFigure);
            app.WhatdiagnosistoolLabel.Text = 'What diagnosis tool?';
            app.GeneralButton = uibutton(app.UIFigure, 'push');
            app.GeneralButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralButtonPushed, true);
            app.GeneralButton.Tooltip = {'Predict a general disease by selecting various symptoms.'};
            app.GeneralButton.Text = 'General';
            app.DiabetesButton = uibutton(app.UIFigure, 'push');
            app.DiabetesButton.ButtonPushedFcn = createCallbackFcn(app, @DiabetesButtonPushed, true);
            app.DiabetesButton.Tooltip = {'Predict diabetes risk by giving various health parameters. '; ''; 'Diabetes is a chronic disease that occurs either when the pancreas does not produce enough insulin or when the body cannot effectively use the insulin it produces.'};
            app.DiabetesButton.Text = 'Diabetes';
            app.HeartDiseaseButton = uibutton(app.UIFigure, 'push');
            app.HeartDiseaseButton.ButtonPushedFcn = createCallbackFcn(app, @HeartDiseaseButtonPushed, true);
            app.HeartDiseaseButton.Tooltip = {'Predict Heart disease (HD) by giving various health parameters. '; ''; 'HD refers to any problem affecting the heart, such as coronary artery disease, arrhythmia, and heart failure.'};
            app.HeartDiseaseButton.Text = 'Heart Disease';
            app.LumpySkinButton = uibutton(app.UIFigure, 'push');
            app.LumpySkinButton.ButtonPushedFcn = createCallbackFcn(app, @LumpySkinButtonPushed, true);
            app.LumpySkinButton.Tooltip = {'Predict Lumpy Skin Disease (LSD) by giving meteorological and geographical data. '; ''; 'LSD is a poxvirus disease of cattle characterised by fever, nodules on the skin, mucous membranes and internal organs, emaciation, enlarged lymph nodes, oedema of the skin, and sometimes death. '};
            app.LumpySkinButton.Text = 'Lumpy Skin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StartUpApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end