classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PredictButton        matlab.ui.control.Button
        DiseasTextArea       matlab.ui.control.TextArea
        DiseasTextAreaLabel  matlab.ui.control.Label
        SymptomsHoldCTRLtoselectmultipleListBox  matlab.ui.control.ListBox
        SymptomsHoldCTRLtoselectmultipleListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MyModel % Description
        MySymptoms % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('ourmodel.mat','MLmodel','unique_symptoms'); %load our model and the unique symptoms
            app.SymptomsHoldCTRLtoselectmultipleListBox.Items = unique_symptoms; %give the symptoms to our selection list

            app.MyModel=MLmodel; % store the ml model in our class
            app.MySymptoms=unique_symptoms; % store the unique symptoms in our class
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            current_feature=zeros(1,size(app.MySymptoms,1)); %create a new feature

            for i=1:size(app.SymptomsHoldCTRLtoselectmultipleListBox.Value,2) %set the selected symptoms to 1 in our feature
                feature_index = find(strcmp(app.MySymptoms,app.SymptomsHoldCTRLtoselectmultipleListBox.Value{1,i}));
                current_feature(1,feature_index)=1;
            end

            app.DiseasTextArea.Value = predict(app.MyModel,current_feature); %use our model to make a prediction and viusalize it in the text box
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SymptomsHoldCTRLtoselectmultipleListBoxLabel = uilabel(app.UIFigure);
            app.SymptomsHoldCTRLtoselectmultipleListBoxLabel.Text = {'Symptoms'; '(Hold CTRL to select multiple)'};
            app.SymptomsHoldCTRLtoselectmultipleListBox = uilistbox(app.UIFigure);
            app.SymptomsHoldCTRLtoselectmultipleListBox.Items = {'A', 'B', 'C'};
            app.SymptomsHoldCTRLtoselectmultipleListBox.Multiselect = 'on';
            app.SymptomsHoldCTRLtoselectmultipleListBox.Value = {};
            app.DiseasTextAreaLabel = uilabel(app.UIFigure);
            app.DiseasTextAreaLabel.Text = 'Diseas';
            app.DiseasTextArea = uitextarea(app.UIFigure);
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end